classdef audioequalizer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        Menu                 matlab.ui.container.Menu
        audioLoad            matlab.ui.container.Menu
        Menu_2               matlab.ui.container.Menu
        HzMenu2k             matlab.ui.container.Menu
        TabGroup             matlab.ui.container.TabGroup
        soundplayer          matlab.ui.container.Tab
        timeUIAxes           matlab.ui.control.UIAxes
        timeBar              matlab.ui.control.Slider
        playpause            matlab.ui.control.StateButton
        timeElapsed          matlab.ui.control.Label
        timeLeft             matlab.ui.control.Label
        audioName            matlab.ui.control.Label
        audioFile            matlab.ui.control.EditField
        choose               matlab.ui.control.Button
        load                 matlab.ui.control.Button
        Label_2              matlab.ui.control.Label
        audiovoice           matlab.ui.control.Slider
        audiovoice_edit      matlab.ui.control.Label
        timeUIAxes_2         matlab.ui.control.UIAxes
        respones             matlab.ui.container.Tab
        eqUIAxes             matlab.ui.control.UIAxes
        frequencyUIAxes      matlab.ui.control.UIAxes
        Tab_3                matlab.ui.container.Tab
        eqUIAxes_2           matlab.ui.control.UIAxes
        frequencyUIAxes_2    matlab.ui.control.UIAxes
        TabGroup2            matlab.ui.container.TabGroup
        Tab                  matlab.ui.container.Tab
        Freq500text          matlab.ui.control.Label
        Freq500              matlab.ui.control.Slider
        dbLabel500           matlab.ui.control.Label
        Spinner_5Label       matlab.ui.control.Label
        Q500                 matlab.ui.control.Spinner
        Freq1ktext           matlab.ui.control.Label
        Freq1k               matlab.ui.control.Slider
        dbLabel1k            matlab.ui.control.Label
        Spinner_6Label       matlab.ui.control.Label
        Q1k                  matlab.ui.control.Spinner
        Freq2ktext           matlab.ui.control.Label
        Freq2k               matlab.ui.control.Slider
        dbLabel2k            matlab.ui.control.Label
        Spinner_7Label       matlab.ui.control.Label
        Q2k                  matlab.ui.control.Spinner
        Freq4ktext           matlab.ui.control.Label
        Freq4k               matlab.ui.control.Slider
        dbLabel4k            matlab.ui.control.Label
        Spinner_8Label       matlab.ui.control.Label
        Q4k                  matlab.ui.control.Spinner
        Freq8ktext           matlab.ui.control.Label
        Freq8k               matlab.ui.control.Slider
        dbLabel8k            matlab.ui.control.Label
        Spinner_9Label       matlab.ui.control.Label
        Q8k                  matlab.ui.control.Spinner
        Freq16ktext          matlab.ui.control.Label
        Freq16k              matlab.ui.control.Slider
        dbLabel16k           matlab.ui.control.Label
        Spinner_10Label      matlab.ui.control.Label
        Q16k                 matlab.ui.control.Spinner
        synchronousButton    matlab.ui.control.Button
        Freq250text          matlab.ui.control.Label
        Freq250              matlab.ui.control.Slider
        dbLabel250           matlab.ui.control.Label
        Spinner_5Label_3     matlab.ui.control.Label
        Q250                 matlab.ui.control.Spinner
        Freq125text          matlab.ui.control.Label
        Freq125              matlab.ui.control.Slider
        dbLabel125           matlab.ui.control.Label
        Spinner_5Label_4     matlab.ui.control.Label
        Q125                 matlab.ui.control.Spinner
        Freq62text           matlab.ui.control.Label
        Freq62               matlab.ui.control.Slider
        dbLabel62            matlab.ui.control.Label
        Spinner_5Label_5     matlab.ui.control.Label
        Q62                  matlab.ui.control.Spinner
        Freq31text           matlab.ui.control.Label
        Freq31               matlab.ui.control.Slider
        dbLabel31            matlab.ui.control.Label
        Spinner_5Label_6     matlab.ui.control.Label
        Q31                  matlab.ui.control.Spinner
        Tab_2                matlab.ui.container.Tab
        synchronousButton_2  matlab.ui.control.Button
        Freq31text_2         matlab.ui.control.Label
        Freq31_2             matlab.ui.control.Slider
        dbLabel31_2          matlab.ui.control.Label
        Spinner_5Label_7     matlab.ui.control.Label
        Q31_2                matlab.ui.control.Spinner
        Freq62text_2         matlab.ui.control.Label
        Freq62_2             matlab.ui.control.Slider
        dbLabel62_2          matlab.ui.control.Label
        Spinner_5Label_8     matlab.ui.control.Label
        Q62_2                matlab.ui.control.Spinner
        Freq125text_2        matlab.ui.control.Label
        Freq125_2            matlab.ui.control.Slider
        dbLabel125_2         matlab.ui.control.Label
        Spinner_5Label_9     matlab.ui.control.Label
        Q125_2               matlab.ui.control.Spinner
        Freq250text_2        matlab.ui.control.Label
        Freq250_2            matlab.ui.control.Slider
        dbLabel250_2         matlab.ui.control.Label
        Spinner_5Label_10    matlab.ui.control.Label
        Q250_2               matlab.ui.control.Spinner
        Freq500text_2        matlab.ui.control.Label
        Freq500_2            matlab.ui.control.Slider
        dbLabel500_2         matlab.ui.control.Label
        Spinner_5Label_11    matlab.ui.control.Label
        Q500_2               matlab.ui.control.Spinner
        Freq1ktext_2         matlab.ui.control.Label
        Freq1k_2             matlab.ui.control.Slider
        dbLabel1k_2          matlab.ui.control.Label
        Spinner_6Label_2     matlab.ui.control.Label
        Q1k_2                matlab.ui.control.Spinner
        Freq2ktext_2         matlab.ui.control.Label
        Freq2k_2             matlab.ui.control.Slider
        dbLabel2k_2          matlab.ui.control.Label
        Spinner_7Label_2     matlab.ui.control.Label
        Q2k_2                matlab.ui.control.Spinner
        Freq4ktext_2         matlab.ui.control.Label
        Freq4k_2             matlab.ui.control.Slider
        dbLabel4k_2          matlab.ui.control.Label
        Spinner_8Label_2     matlab.ui.control.Label
        Q4k_2                matlab.ui.control.Spinner
        Freq8ktext_2         matlab.ui.control.Label
        Freq8k_2             matlab.ui.control.Slider
        dbLabel8k_2          matlab.ui.control.Label
        Spinner_9Label_2     matlab.ui.control.Label
        Q8k_2                matlab.ui.control.Spinner
        Freq16ktext_2        matlab.ui.control.Label
        Freq16k_2            matlab.ui.control.Slider
        dbLabel16k_2         matlab.ui.control.Label
        Spinner_10Label_2    matlab.ui.control.Label
        Q16k_2               matlab.ui.control.Spinner
    end

    
    properties (Access = private)
        audio%音频原文件
        audioTrack%音频文件
        audioFs%采样频率
        soundName%音频文件名
        soundChannel%音道数
        pauseTime=0%暂停处时间
        oldvoice=1
    end
    
    methods (Access = private)
        
        
        function [pauseTime] = playFile(app, audioFile , startTime, playPause)
            totalTime = audioFile.TotalSamples / audioFile.SampleRate;
            audioFile.TimerFcn={@updateSlider,app,audioFile,totalTime};
            if (strcmp(playPause, 'Play') == 1) 
                startPosition = startTime * audioFile.SampleRate;
                playblocking(audioFile, round(startPosition));
            elseif (strcmp(playPause, 'Pause') == 1) 
                stop(audioFile);
                pauseTime = get(app.timeBar, 'Value') * totalTime;
            end
        end
        
        function ploteqUIAxes(app,channel,fs,h,w,h_2,w_2)%滤波器频率响应绘制函数
            if channel==1
                plot(app.eqUIAxes,w*fs/2/pi,h);
                if (strcmp(get(app.HzMenu2k, 'Checked'), 'on') == 1)
                    axis(app.eqUIAxes,[0,2000,-12,12]);
                end
            elseif channel==2
%                 plot(app.eqUIAxes,w*fs/2/pi,h);
                plot(app.eqUIAxes_2,w_2*fs/2/pi,h_2);
                if (strcmp(get(app.HzMenu2k, 'Checked'), 'on') == 1)
%                     axis(app.eqUIAxes,[0,2000,-12,12]);
                    axis(app.eqUIAxes_2,[0,2000,-12,12]);
                end
            else
                  return
            end
        end
    
        function plotaudioUIAxes(app,audioFile,channel)%时频图绘制函数
            n=audioFile.TotalSamples;
            fs=audioFile.SampleRate;
            t = (0:n-1)/fs;
            f=(0:n-1)*fs/n;%真实频率
            if channel==1
                Audio_ff = abs( fft( audioFile.UserData(:,1),n ) );
                plot(app.timeUIAxes,t,audioFile.UserData(:,1),'b');
                axis(app.timeUIAxes,[app.pauseTime-2,app.pauseTime+2,-1,1]);
                xticks(app.timeUIAxes,app.pauseTime);
                xticklabels(app.timeUIAxes,{''});
                plot( app.frequencyUIAxes,f(1:floor(n/2)),abs(Audio_ff(1:floor(n/2))) );
                if (strcmp(get(app.HzMenu2k, 'Checked'), 'on') == 1)
                    axis(app.frequencyUIAxes,[0,2000,0,max(Audio_ff)+100]);
                end
                if (strcmp(get(app.HzMenu2k, 'Checked'), 'off') == 1)
                    axis(app.frequencyUIAxes,[0,(n-1)*fs/2/n,0,max(Audio_ff)+100]);
                end
            elseif channel==2
                Audio_ff_2 = abs( fft( audioFile.UserData(:,2),n ) );
                plot(app.timeUIAxes_2,t,audioFile.UserData(:,2),'b');
                axis(app.timeUIAxes_2,[app.pauseTime-2,app.pauseTime+2,-1,1]);
                xticks(app.timeUIAxes_2,app.pauseTime);
                xticklabels(app.timeUIAxes_2,{''});
                plot( app.frequencyUIAxes_2,f(1:floor(n/2)),abs(Audio_ff_2(1:floor(n/2))) );
                if (strcmp(get(app.HzMenu2k, 'Checked'), 'on') == 1)
                    axis(app.frequencyUIAxes_2,[0,2000,0,max(abs(Audio_ff_2))+100]);
                end
                if (strcmp(get(app.HzMenu2k, 'Checked'), 'off') == 1)
                    axis(app.frequencyUIAxes_2,[0,(n-1)*fs/2/n,0,max(Audio_ff_2)+100]);
                end
            end
        end
    
        function [ num , den ] = peakfilt( ~ , dBgain ,fs, f0 , q )
            %===============IIR设计峰值滤波器===============   
            %中心频率f0，品质因素q，采样频率fs，中心频率增益dBgain
            w=2*pi*f0/fs;
            alpha=sin(w)/(2*q);
            A=10^(dBgain/40);
            num=[1+alpha*A,-2*cos(w),1-alpha*A];
            den=[(1+alpha/A),-2*cos(w),(1-alpha/A)];
            num=num./den(1);den=den./den(1);
        end
    
        function [ h , w , Y ] = updateandfilt( app ,  audioFile , Number ,changingValue)
            A = round(get(app.Freq31 , 'Value'),2);B = round(get(app.Freq62 , 'Value'),2);
            C = round(get(app.Freq125 , 'Value'),2);D = round(get(app.Freq250 , 'Value'),2);
            E = round(get(app.Freq500 , 'Value'),2);F = round(get(app.Freq1k , 'Value'),2);
            G = round(get(app.Freq2k , 'Value'),2);H = round(get(app.Freq4k , 'Value'),2);
            I = round(get(app.Freq8k , 'Value'),2);J = round(get(app.Freq16k , 'Value'),2);
            
            A1 = round(get(app.Q31 , 'Value'),2);B1 = round(get(app.Q62 , 'Value'),2);
            C1 = round(get(app.Q125 , 'Value'),2);D1 = round(get(app.Q250 , 'Value'),2);
            E1 = round(get(app.Q500 , 'Value'),2);F1 = round(get(app.Q1k , 'Value'),2);
            G1 = round(get(app.Q2k , 'Value'),2);H1 = round(get(app.Q4k , 'Value'),2);
            I1 = round(get(app.Q8k , 'Value'),2);J1 = round(get(app.Q16k , 'Value'),2);
            switch Number
                case 0
                
                case 1
                    A1=changingValue;
                case 2
                    B1=changingValue;
                case 3
                    C1=changingValue;
                case 4
                    D1=changingValue;
                case 5
                    E1=changingValue;
                case 6
                    F1=changingValue;
                case 7
                    G1=changingValue;
                case 8
                    H1=changingValue;
                case 9
                    I1=changingValue;
                case 10
                    J1=changingValue;
            end
            [ numA , denA ] = peakfilt( app , A ,audioFile.SampleRate, 31 , A1 );
            [ numB , denB ] = peakfilt( app , B ,audioFile.SampleRate, 62 , B1 );
            [ numC , denC ] = peakfilt( app , C ,audioFile.SampleRate, 125 , C1 );
            [ numD , denD ] = peakfilt( app , D ,audioFile.SampleRate, 250 , D1 );
            [ numE , denE ] = peakfilt( app , E ,audioFile.SampleRate, 500 , E1 );
            [ numF , denF ] = peakfilt( app , F ,audioFile.SampleRate, 1000 , F1 );
            [ numG , denG ] = peakfilt( app , G ,audioFile.SampleRate, 2000 , G1 );
            [ numH , denH ] = peakfilt( app , H ,audioFile.SampleRate, 4000 , H1 );
            [ numI , denI ] = peakfilt( app , I ,audioFile.SampleRate, 8000 , I1 );
            [ numJ , denJ ] = peakfilt( app , J ,audioFile.SampleRate, 16000 , J1 );
            [hA,w]=freqz(numA,denA,floor(audioFile.TotalSamples/2));[hB,~]=freqz(numB,denB,floor(audioFile.TotalSamples/2));
            [hC,~]=freqz(numC,denC,floor(audioFile.TotalSamples/2));[hD,~]=freqz(numD,denD,floor(audioFile.TotalSamples/2));
            [hE,~]=freqz(numE,denE,floor(audioFile.TotalSamples/2));[hF,~]=freqz(numF,denF,floor(audioFile.TotalSamples/2));
            [hG,~]=freqz(numG,denG,floor(audioFile.TotalSamples/2));[hH,~]=freqz(numH,denH,floor(audioFile.TotalSamples/2));
            [hI,~]=freqz(numI,denI,floor(audioFile.TotalSamples/2));[hJ,~]=freqz(numJ,denJ,floor(audioFile.TotalSamples/2));
            h=20*log10(abs(hA).*abs(hB).*abs(hC).*abs(hD).*abs(hE).*abs(hF).*abs(hG).*abs(hH).*abs(hI).*abs(hJ));
            Y=filtfilt(numJ,denJ,filtfilt(numI,denI,filtfilt(numH,denH,...
                filtfilt(numG,denG,filtfilt(numF,denF,filtfilt(numE,denE,filtfilt(numD,denD,...
                filtfilt(numC,denC,filtfilt(numB,denB,filtfilt(numA,denA,audioFile.UserData(:,1)))))))))));
        end
        
        function [ h_2 , w_2 , Y_2 ] = updateandfilt_2( app , audioFile , Number ,changingValue)
            A = round(get(app.Freq31_2 , 'Value'),2);B = round(get(app.Freq62_2 , 'Value'),2);
            C = round(get(app.Freq125_2 , 'Value'),2);D = round(get(app.Freq250_2 , 'Value'),2);
            E = round(get(app.Freq500_2 , 'Value'),2);F = round(get(app.Freq1k_2 , 'Value'),2);
            G = round(get(app.Freq2k_2 , 'Value'),2);H = round(get(app.Freq4k_2 , 'Value'),2);
            I = round(get(app.Freq8k_2 , 'Value'),2);J = round(get(app.Freq16k_2 , 'Value'),2);
            
            A1 = round(get(app.Q31_2 , 'Value'),2);B1 = round(get(app.Q62_2 , 'Value'),2);
            C1 = round(get(app.Q125_2 , 'Value'),2);D1 = round(get(app.Q250_2 , 'Value'),2);
            E1 = round(get(app.Q500_2 , 'Value'),2);F1 = round(get(app.Q1k_2 , 'Value'),2);
            G1 = round(get(app.Q2k_2 , 'Value'),2);H1 = round(get(app.Q4k_2 , 'Value'),2);
            I1 = round(get(app.Q8k_2 , 'Value'),2);J1 = round(get(app.Q16k_2 , 'Value'),2);
            switch Number
                case 0
                    
                case 1
                    A1=changingValue;
                case 2
                    B1=changingValue;
                case 3
                    C1=changingValue;
                case 4
                    D1=changingValue;
                case 5
                    E1=changingValue;
                case 6
                    F1=changingValue;
                case 7
                    G1=changingValue;
                case 8
                    H1=changingValue;
                case 9
                    I1=changingValue;
                case 10
                    J1=changingValue;
            end
            
            [ numA , denA ] = peakfilt( app , A ,audioFile.SampleRate, 31 , A1 );
            [ numB , denB ] = peakfilt( app , B ,audioFile.SampleRate, 62 , B1 );
            [ numC , denC ] = peakfilt( app , C ,audioFile.SampleRate, 125 , C1 );
            [ numD , denD ] = peakfilt( app , D ,audioFile.SampleRate, 250 , D1 );
            [ numE , denE ] = peakfilt( app , E ,audioFile.SampleRate, 500 , E1 );
            [ numF , denF ] = peakfilt( app , F ,audioFile.SampleRate, 1000 , F1 );
            [ numG , denG ] = peakfilt( app , G ,audioFile.SampleRate, 2000 , G1 );
            [ numH , denH ] = peakfilt( app , H ,audioFile.SampleRate, 4000 , H1 );
            [ numI , denI ] = peakfilt( app , I ,audioFile.SampleRate, 8000 , I1 );
            [ numJ , denJ ] = peakfilt( app , J ,audioFile.SampleRate, 16000 , J1 );
            [hA,w_2]=freqz(numA,denA,floor(audioFile.TotalSamples/2));[hB,~]=freqz(numB,denB,floor(audioFile.TotalSamples/2));
            [hC,~]=freqz(numC,denC,floor(audioFile.TotalSamples/2));[hD,~]=freqz(numD,denD,floor(audioFile.TotalSamples/2));
            [hE,~]=freqz(numE,denE,floor(audioFile.TotalSamples/2));[hF,~]=freqz(numF,denF,floor(audioFile.TotalSamples/2));
            [hG,~]=freqz(numG,denG,floor(audioFile.TotalSamples/2));[hH,~]=freqz(numH,denH,floor(audioFile.TotalSamples/2));
            [hI,~]=freqz(numI,denI,floor(audioFile.TotalSamples/2));[hJ,~]=freqz(numJ,denJ,floor(audioFile.TotalSamples/2));
            h_2=20*log10(abs(hA).*abs(hB).*abs(hC).*abs(hD).*abs(hE).*abs(hF).*abs(hG).*abs(hH).*abs(hI).*abs(hJ));
            Y_2=filtfilt(numJ,denJ,filtfilt(numI,denI,filtfilt(numH,denH,...
                filtfilt(numG,denG,filtfilt(numF,denF,filtfilt(numE,denE,filtfilt(numD,denD,...
                filtfilt(numC,denC,filtfilt(numB,denB,filtfilt(numA,denA,audioFile.UserData(:,2)))))))))));
        end
    
        function updateFreqtext(app,Number)
            db='db';
            %单声道
            if app.soundChannel==1
                switch Number
                    case 1
                        A = round(get(app.Freq31 , 'Value'),2);
                        set(app.dbLabel31,'Text', strcat(num2str( A ),db));
                    case 2
                        A = round(get(app.Freq62 , 'Value'),2);
                        set(app.dbLabel62,'Text', strcat(num2str( A ),db));
                    case 3
                        A = round(get(app.Freq125 , 'Value'),2);
                        set(app.dbLabel125,'Text', strcat(num2str( A ),db));
                    case 4
                        A = round(get(app.Freq250 , 'Value'),2);
                        set(app.dbLabel250,'Text', strcat(num2str( A ),db));
                    case 5
                        A = round(get(app.Freq500 , 'Value'),2);
                        set(app.dbLabel500,'Text', strcat(num2str( A ),db));
                    case 6
                        A = round(get(app.Freq1k , 'Value'),2);
                        set(app.dbLabel1k,'Text', strcat(num2str( A ),db));
                    case 7
                        A = round(get(app.Freq2k , 'Value'),2);
                        set(app.dbLabel2k,'Text', strcat(num2str( A ),db));
                    case 8
                        A = round(get(app.Freq4k , 'Value'),2);
                        set(app.dbLabel4k,'Text', strcat(num2str( A ),db));
                    case 9
                        A = round(get(app.Freq8k , 'Value'),2);
                        set(app.dbLabel8k,'Text', strcat(num2str( A ),db));
                    case 10
                        A = round(get(app.Freq16k , 'Value'),2);
                        set(app.dbLabel16k,'Text', strcat(num2str( A ),db));
                end
            %双声道
            elseif app.soundChannel==2
                switch Number
                    case 1
                        A = round(get(app.Freq31 , 'Value'),2);
                        set(app.dbLabel31,'Text', strcat(num2str( A ),db));
                        A = round(get(app.Freq31_2 , 'Value'),2);
                        set(app.dbLabel31_2,'Text', strcat(num2str( A ),db));
                    case 2
                        A = round(get(app.Freq62 , 'Value'),2);
                        set(app.dbLabel62,'Text', strcat(num2str( A ),db));
                        A = round(get(app.Freq62_2 , 'Value'),2);
                        set(app.dbLabel62_2,'Text', strcat(num2str( A ),db));
                    case 3
                        A = round(get(app.Freq125 , 'Value'),2);
                        set(app.dbLabel125,'Text', strcat(num2str( A ),db));
                        A = round(get(app.Freq125_2 , 'Value'),2);
                        set(app.dbLabel125_2,'Text', strcat(num2str( A ),db));
                    case 4
                        A = round(get(app.Freq250 , 'Value'),2);
                        set(app.dbLabel250,'Text', strcat(num2str( A ),db));
                        A = round(get(app.Freq250_2 , 'Value'),2);
                        set(app.dbLabel250_2,'Text', strcat(num2str( A ),db));
                    case 5
                        A = round(get(app.Freq500 , 'Value'),2);
                        set(app.dbLabel500,'Text', strcat(num2str( A ),db));
                        A = round(get(app.Freq500_2 , 'Value'),2);
                        set(app.dbLabel500_2,'Text', strcat(num2str( A ),db));
                    case 6
                        A = round(get(app.Freq1k , 'Value'),2);
                        set(app.dbLabel1k,'Text', strcat(num2str( A ),db));
                        A = round(get(app.Freq1k_2 , 'Value'),2);
                        set(app.dbLabel1k_2,'Text', strcat(num2str( A ),db));
                    case 7
                        A = round(get(app.Freq2k , 'Value'),2);
                        set(app.dbLabel2k,'Text', strcat(num2str( A ),db));
                        A = round(get(app.Freq2k_2 , 'Value'),2);
                        set(app.dbLabel2k_2,'Text', strcat(num2str( A ),db));
                    case 8
                        A = round(get(app.Freq4k , 'Value'),2);
                        set(app.dbLabel4k,'Text', strcat(num2str( A ),db));
                        A = round(get(app.Freq4k_2 , 'Value'),2);
                        set(app.dbLabel4k_2,'Text', strcat(num2str( A ),db));
                    case 9
                        A = round(get(app.Freq8k , 'Value'),2);
                        set(app.dbLabel8k,'Text', strcat(num2str( A ),db));
                        A = round(get(app.Freq8k_2 , 'Value'),2);
                        set(app.dbLabel8k_2,'Text', strcat(num2str( A ),db));
                    case 10
                        A = round(get(app.Freq16k , 'Value'),2);
                        set(app.dbLabel16k,'Text', strcat(num2str( A ),db));
                        A = round(get(app.Freq16k_2 , 'Value'),2);
                        set(app.dbLabel16k_2,'Text', strcat(num2str( A ),db));
                end
            end
        end
        
        function setsilier(app,onoff)
            set(app.Freq31 , 'Enable',onoff);set(app.Freq62 , 'Enable',onoff);set(app.Freq125 , 'Enable',onoff);
            set(app.Freq250 , 'Enable',onoff);set(app.Freq500 , 'Enable',onoff);set(app.Freq1k , 'Enable',onoff);
            set(app.Freq2k , 'Enable',onoff);set(app.Freq4k , 'Enable',onoff);set(app.Freq8k , 'Enable',onoff);
            set(app.Freq16k , 'Enable',onoff);
            set(app.Q31 , 'Enable',onoff);set(app.Q62 , 'Enable',onoff);set(app.Q125 , 'Enable',onoff);
            set(app.Q250 , 'Enable',onoff);set(app.Q500 , 'Enable',onoff);set(app.Q1k , 'Enable',onoff);
            set(app.Q2k , 'Enable',onoff);set(app.Q4k , 'Enable',onoff);set(app.Q8k , 'Enable',onoff);
            set(app.Q16k , 'Enable',onoff);
            set(app.Q31 , 'Editable',onoff);set(app.Q62 , 'Editable',onoff);set(app.Q125 , 'Editable',onoff);
            set(app.Q250 , 'Editable',onoff);set(app.Q500 , 'Editable',onoff);set(app.Q1k , 'Editable',onoff);
            set(app.Q2k , 'Editable',onoff);set(app.Q4k , 'Editable',onoff);set(app.Q8k , 'Editable',onoff);
            set(app.Q16k , 'Editable',onoff);
        end
    
        function setsilier_2(app,onoff)
            set(app.Freq31_2 , 'Enable',onoff);set(app.Freq62_2 , 'Enable',onoff);set(app.Freq125_2 , 'Enable',onoff);
            set(app.Freq250_2 , 'Enable',onoff);set(app.Freq500_2 , 'Enable',onoff);set(app.Freq1k_2 , 'Enable',onoff);
            set(app.Freq2k_2 , 'Enable',onoff);set(app.Freq4k_2 , 'Enable',onoff);set(app.Freq8k_2 , 'Enable',onoff);
            set(app.Freq16k_2 , 'Enable',onoff);
            set(app.Q31_2 , 'Enable',onoff);set(app.Q62_2 , 'Enable',onoff);set(app.Q125_2 , 'Enable',onoff);
            set(app.Q250_2 , 'Enable',onoff);set(app.Q500_2 , 'Enable',onoff);set(app.Q1k_2 , 'Enable',onoff);
            set(app.Q2k_2 , 'Enable',onoff);set(app.Q4k_2 , 'Enable',onoff);set(app.Q8k_2 , 'Enable',onoff);
            set(app.Q16k_2 , 'Enable',onoff);
            set(app.Q31_2 , 'Editable',onoff);set(app.Q62_2 , 'Editable',onoff);set(app.Q125_2 , 'Editable',onoff);
            set(app.Q250_2 , 'Editable',onoff);set(app.Q500_2 , 'Editable',onoff);set(app.Q1k_2 , 'Editable',onoff);
            set(app.Q2k_2 , 'Editable',onoff);set(app.Q4k_2 , 'Editable',onoff);set(app.Q8k_2 , 'Editable',onoff);
            set(app.Q16k_2 , 'Editable',onoff);
            set(app.synchronousButton,'Enable',onoff);
            set(app.synchronousButton_2,'Enable',onoff);
        end
        
        function reset(app,Freq,Q,time)
            set(app.Freq31,'Value',Freq);set(app.Freq62,'Value',Freq);set(app.Freq125,'Value',Freq);set(app.Freq250,'Value',Freq);
            set(app.Freq500,'Value',Freq);set(app.Freq1k,'Value',Freq);set(app.Freq2k,'Value',Freq);set(app.Freq4k,'Value',Freq);
            set(app.Freq8k,'Value',Freq);set(app.Freq16k,'Value',Freq);
            set(app.Q31,'Value',Q);set(app.Q62,'Value',Q);set(app.Q125,'Value',Q);set(app.Q250,'Value',Q);
            set(app.Q500,'Value',Q);set(app.Q1k,'Value',Q);set(app.Q2k,'Value',Q);set(app.Q4k,'Value',Q);
            set(app.Q8k,'Value',Q);set(app.Q16k,'Value',Q);
            
            set(app.Freq31_2,'Value',Freq);set(app.Freq62_2,'Value',Freq);set(app.Freq125_2,'Value',Freq);set(app.Freq250_2,'Value',Freq);
            set(app.Freq500_2,'Value',Freq);set(app.Freq1k_2,'Value',Freq);set(app.Freq2k_2,'Value',Freq);set(app.Freq4k_2,'Value',Freq);
            set(app.Freq8k_2,'Value',Freq);set(app.Freq16k_2,'Value',Freq);
            set(app.Q31_2,'Value',Q);set(app.Q62_2,'Value',Q);set(app.Q125_2,'Value',Q);set(app.Q250_2,'Value',Q);
            set(app.Q500_2,'Value',Q);set(app.Q1k_2,'Value',Q);set(app.Q2k_2,'Value',Q);set(app.Q4k_2,'Value',Q);
            set(app.Q8k_2,'Value',Q);set(app.Q16k_2,'Value',Q);
            
            set(app.timeBar,'Value',time);
            updateFreqtext(app,1);updateFreqtext(app,2);
            updateFreqtext(app,3);updateFreqtext(app,4);
            updateFreqtext(app,5);updateFreqtext(app,6);
            updateFreqtext(app,7);updateFreqtext(app,8);
            updateFreqtext(app,9);updateFreqtext(app,10);
        end
    end
    

    methods (Access = private)

        % Button pushed function: choose
        function chooseButtonPushed(app, event)
            [fileName, pathName] = uigetfile('*.wav');%选择文件
            fullPathName = strcat(pathName, fileName);
            [~,~,ext]=fileparts(fullPathName);
            if (strcmp(ext,'.wav')==0)&&(strcmp(ext,'')==0)
                msgbox("请选择.wav文件","错误","error");
                return
            end
            if(ischar(fileName)) 
                [data, app.audioFs] = audioread(fullPathName);
                app.audioTrack = audioplayer(data, app.audioFs);
                app.audioTrack.UserData = data;
                app.soundChannel=app.audioTrack.NumberOfChannels;
                app.audio = audioplayer(data, app.audioFs);
                app.audio.UserData = data;
                set(app.audioFile,'Value',fullPathName);
            end
        end

        % Button pushed function: load
        function loadButtonPushed(app, event)
            reset(app,0,1,0);
            [filepath,name,ext]=fileparts(app.audioFile.Value);
            if strcmp(filepath,'')==1||strcmp(ext,'')==1
                msgbox("未指定文件路径或扩展名","错误","error");
                return
            end
            [data, app.audioFs] = audioread(app.audioFile.Value);
            data=app.audiovoice.Value*data;
            app.audioTrack = audioplayer(data, app.audioFs);
            app.audioTrack.UserData=data;
            %设置音频名
            app.soundName=strcat(name,ext);
            set(app.audioName,'Text',app.soundName);
            totalTime = app.audioTrack.TotalSamples / app.audioTrack.SampleRate; %采样长度除以采样频率
            app.pauseTime = totalTime * get(app.timeBar, 'Value'); %更新暂停时间
            playFile(app, app.audioTrack , app.pauseTime, 'Pause');
            %设置已播放时长
            [hours, minutes, seconds] = hoursMinsSecs(app.pauseTime);
            timeElapsedString = strcat(num2str(hours,'%02.0f'), ':', num2str(minutes,'%02.0f'), ':', num2str(seconds,'%02.0f'));
            set(app.timeElapsed, 'Text', timeElapsedString)
            %设置剩余时长
            [hoursLeft, minutesLeft, secondsLeft] = hoursMinsSecs(totalTime - app.pauseTime);
            timeLeftString = strcat('-', num2str(hoursLeft,'%02.0f'), ':', num2str(minutesLeft,'%02.0f'), ':', num2str(secondsLeft,'%02.0f'));
            set(app.timeLeft, 'Text', timeLeftString)
            %设置均衡器可编辑
            if (app.soundChannel==1)
                setsilier(app,'on');
                setsilier_2(app,'off');
            elseif (app.soundChannel==2)
                setsilier(app,'on');
                setsilier_2(app,'on');
            else
                
            end
            %绘制频响图;绘制通道1、2的时域图和频域图
            if app.soundChannel==1
                [ h , w , ~ ] = updateandfilt( app ,  app.audioTrack , 0 ,1);
                ploteqUIAxes(app,1,app.audioTrack.SampleRate,h,w,0,0);
                plotaudioUIAxes(app,app.audioTrack,1);
            elseif app.soundChannel==2
                [ h , w , ~ ] = updateandfilt( app ,  app.audioTrack , 0 ,1);
                [ h_2 , w_2 , ~ ] = updateandfilt_2( app ,  app.audioTrack , 0 ,1);
                ploteqUIAxes(app,1,app.audioTrack.SampleRate,h,w,h_2,w_2);
                ploteqUIAxes(app,2,app.audioTrack.SampleRate,h,w,h_2,w_2);
                plotaudioUIAxes(app,app.audioTrack,1);
                plotaudioUIAxes(app,app.audioTrack,2);
            end
            %绘制通道1、2的时域图和频域图
%             plotaudioUIAxes(app,app.audioTrack,1);
%             plotaudioUIAxes(app,app.audioTrack,2);
        end

        % Value changed function: playpause
        function playpauseValueChanged(app, event)
            value = app.playpause.Value;
            if strcmp(get(app.audioName,'Text'),'No File Loaded')==1
                set(app.playpause,'Value',0);
                return 
            end
            if value==0
                app.pauseTime=playFile(app, app.audioTrack , app.pauseTime, 'Pause');
            else
                playFile(app, app.audioTrack , app.pauseTime, 'Play');
            end
        end

        % Value changed function: timeBar
        function timeBarValueChanged(app, event)
            if (strcmp(get(app.audioName, 'Text'), 'No File Loaded') == 1) %音频未加载则重设到0时刻且不执行
                set(app.timeBar, 'Value', 0);
                return
            end
            playFile(app, app.audioTrack, app.pauseTime, 'Pause');%暂停
            if ( get(app.timeBar, 'Value') == 1 )
                app.playpause.Value = 0;
            end
            %更新时域图像
            totalTime = app.audioTrack.TotalSamples / app.audioTrack.SampleRate;
            app.pauseTime = totalTime * get(app.timeBar, 'Value'); %更新暂停时间
            axis(app.timeUIAxes,[app.pauseTime-2,app.pauseTime+2,-1,1]);
            xticks(app.timeUIAxes,app.pauseTime);
            xticklabels(app.timeUIAxes,{''});
            if app.audioTrack.NumberOfChannels==2
                axis(app.timeUIAxes_2,[app.pauseTime-2,app.pauseTime+2,-1,1]);
                xticks(app.timeUIAxes_2,app.pauseTime);
                xticklabels(app.timeUIAxes_2,{''});
            end
            %更新时间
            totalTime = app.audioTrack.TotalSamples / app.audioTrack.SampleRate; 
            app.pauseTime = totalTime * get(app.timeBar, 'Value'); 
            [hours, minutes, seconds] = hoursMinsSecs(app.pauseTime);
            timeElapsedString = strcat(num2str(hours,'%02.0f'), ':', num2str(minutes,'%02.0f'), ':', num2str(seconds,'%02.0f'));
            set(app.timeElapsed, 'Text', timeElapsedString)
            
            [hoursLeft, minutesLeft, secondsLeft] = hoursMinsSecs(totalTime - app.pauseTime);
            timeLeftString = strcat('-', num2str(hoursLeft,'%02.0f'), ':', num2str(minutesLeft,'%02.0f'), ':', num2str(secondsLeft,'%02.0f'));
            set(app.timeLeft, 'Text', timeLeftString)
            %如果歌曲正在播放
            if (app.playpause.Value == 1)
                playFile(app, app.audioTrack, app.pauseTime, 'Play');%播放
            end
        end

        % Value changing function: audiovoice
        function audiovoiceValueChanging(app, event)
            changingValue = event.Value;
            changingValue = round(changingValue/2*100);
            set(app.audiovoice_edit,'Text',num2str(changingValue));
        end

        % Value changed function: Freq500
        function Freq500ValueChanged(app, event)
            value = app.Freq500.Value;
            %先暂停后处理
            app.pauseTime=playFile(app, app.audioTrack , app.pauseTime, 'Pause');
            %更新text
            updateFreqtext(app,5);
            %通道1滤波
            [ h , w , Y ] = updateandfilt( app ,  app.audio , 0 ,value);
            data=Y;
            if app.soundChannel==2%如果是双声道则读取通道2信号
                Y_2=app.audioTrack.UserData(:,2);
                data=[Y,Y_2];
            end
            data=app.audiovoice.Value*data;
            app.audioTrack = audioplayer(data, app.audioTrack.SampleRate);%更新audioTrack
            app.audioTrack.UserData=data;%将音频信号矩阵存入audioTrack.UserData
            ploteqUIAxes(app,1,app.audioTrack.SampleRate,h,w,0,0);
            plotaudioUIAxes(app,app.audioTrack,1);
            %处于播放状态则播放
            if app.playpause.Value==1
                playFile(app, app.audioTrack , app.pauseTime, 'Play');
            end
        end

        % Value changed function: Freq1k
        function Freq1kValueChanged(app, event)
            value = app.Freq1k.Value;
            app.pauseTime=playFile(app, app.audioTrack , app.pauseTime, 'Pause');
            updateFreqtext(app,6);
            [ h , w , Y ] = updateandfilt( app ,  app.audio , 0 ,value);
            data=Y;
            if app.soundChannel==2
                Y_2=app.audioTrack.UserData(:,2);
                data=[Y,Y_2];
            end
            data=app.audiovoice.Value*data;
            app.audioTrack = audioplayer(data, app.audioTrack.SampleRate);%更新audioTrack
            app.audioTrack.UserData=data;
            ploteqUIAxes(app,1,app.audioTrack.SampleRate,h,w,0,0);
            plotaudioUIAxes(app,app.audioTrack,1);
            if app.playpause.Value==1
                playFile(app, app.audioTrack , app.pauseTime, 'Play');
            end
        end

        % Value changed function: Freq2k
        function Freq2kValueChanged(app, event)
            value = app.Freq2k.Value;
            app.pauseTime=playFile(app, app.audioTrack , app.pauseTime, 'Pause');
            updateFreqtext(app,7);
            [ h , w , Y ] = updateandfilt( app ,  app.audio , 0 ,value);
            data=Y;
            if app.soundChannel==2
                Y_2=app.audioTrack.UserData(:,2);
                data=[Y,Y_2];
            end
            data=app.audiovoice.Value*data;
            app.audioTrack = audioplayer(data, app.audioTrack.SampleRate);%更新audioTrack
            app.audioTrack.UserData=data;
            ploteqUIAxes(app,1,app.audioTrack.SampleRate,h,w,0,0);
            plotaudioUIAxes(app,app.audioTrack,1);

            if app.playpause.Value==1
                playFile(app, app.audioTrack , app.pauseTime, 'Play');
            end
        end

        % Value changed function: Freq4k
        function Freq4kValueChanged(app, event)
            value = app.Freq4k.Value;
            app.pauseTime=playFile(app, app.audioTrack , app.pauseTime, 'Pause');
            updateFreqtext(app,8);
            [ h , w , Y ] = updateandfilt( app ,  app.audio , 0 ,value);
            data=Y;
            if app.soundChannel==2
                Y_2=app.audioTrack.UserData(:,2);
                data=[Y,Y_2];
            end
            data=app.audiovoice.Value*data;
            app.audioTrack = audioplayer(data, app.audioTrack.SampleRate);%更新audioTrack
            app.audioTrack.UserData=data;
            ploteqUIAxes(app,1,app.audioTrack.SampleRate,h,w,0,0);
            plotaudioUIAxes(app,app.audioTrack,1);
            if app.playpause.Value==1
                playFile(app, app.audioTrack , app.pauseTime, 'Play');
            end
        end

        % Value changed function: Freq8k
        function Freq8kValueChanged(app, event)
            value = app.Freq8k.Value;
            app.pauseTime=playFile(app, app.audioTrack , app.pauseTime, 'Pause');
            updateFreqtext(app,9);
            [ h , w , Y ] = updateandfilt( app ,  app.audio , 0 ,value);
            data=Y;
            if app.soundChannel==2
                Y_2=app.audioTrack.UserData(:,2);
                data=[Y,Y_2];
            end
            data=app.audiovoice.Value*data;
            app.audioTrack = audioplayer(data, app.audioTrack.SampleRate);%更新audioTrack
            app.audioTrack.UserData=data;
            ploteqUIAxes(app,1,app.audioTrack.SampleRate,h,w,0,0);
            plotaudioUIAxes(app,app.audioTrack,1);
            if app.playpause.Value==1
                playFile(app, app.audioTrack , app.pauseTime, 'Play');
            end
        end

        % Value changed function: Freq16k
        function Freq16kValueChanged(app, event)
            value = app.Freq16k.Value;
            app.pauseTime=playFile(app, app.audioTrack , app.pauseTime, 'Pause');
            updateFreqtext(app,10);
            [ h , w , Y ] = updateandfilt( app ,  app.audio , 0 ,value);
            data=Y;
            if app.soundChannel==2
                Y_2=app.audioTrack.UserData(:,2);
                data=[Y,Y_2];
            end
            data=app.audiovoice.Value*data;
            app.audioTrack = audioplayer(data, app.audioTrack.SampleRate);%更新audioTrack
            app.audioTrack.UserData=data;
            ploteqUIAxes(app,1,app.audioTrack.SampleRate,h,w,0,0);
            plotaudioUIAxes(app,app.audioTrack,1);
            if app.playpause.Value==1
                playFile(app, app.audioTrack , app.pauseTime, 'Play');
            end
        end

        % Value changing function: Q500
        function Q500ValueChanging(app, event)
            changingValue = event.Value;
            app.pauseTime=playFile(app, app.audioTrack , app.pauseTime, 'Pause');
            [ h , w , Y ] = updateandfilt( app ,  app.audio , 5 ,changingValue);
            data=Y;
            if app.soundChannel==2
                Y_2=app.audioTrack.UserData(:,2);
                data=[Y,Y_2];
            end
            data=app.audiovoice.Value*data;
            app.audioTrack = audioplayer(data, app.audioTrack.SampleRate);%更新audioTrack
            app.audioTrack.UserData=data;
            ploteqUIAxes(app,1,app.audioTrack.SampleRate,h,w,0,0);
            plotaudioUIAxes(app,app.audioTrack,1);
            if app.playpause.Value==1
                playFile(app, app.audioTrack , app.pauseTime, 'Play');
            end
        end

        % Value changing function: Q1k
        function Q1kValueChanging(app, event)
            changingValue = event.Value;
            app.pauseTime=playFile(app, app.audioTrack , app.pauseTime, 'Pause');
            [ h , w , Y ] = updateandfilt( app ,  app.audio , 6 ,changingValue);
            data=Y;
            if app.soundChannel==2
                Y_2=app.audioTrack.UserData(:,2);
                data=[Y,Y_2];
            end
            data=app.audiovoice.Value*data;
            app.audioTrack = audioplayer(data, app.audioTrack.SampleRate);%更新audioTrack
            app.audioTrack.UserData=data;
            ploteqUIAxes(app,1,app.audioTrack.SampleRate,h,w,0,0);
            plotaudioUIAxes(app,app.audioTrack,1);
            if app.playpause.Value==1
                playFile(app, app.audioTrack , app.pauseTime, 'Play');
            end
        end

        % Value changing function: Q2k
        function Q2kValueChanging(app, event)
            changingValue = event.Value;
            app.pauseTime=playFile(app, app.audioTrack , app.pauseTime, 'Pause');
            [ h , w , Y ] = updateandfilt( app ,  app.audio , 7 ,changingValue);
            data=Y;
            if app.soundChannel==2
                Y_2=app.audioTrack.UserData(:,2);
                data=[Y,Y_2];
            end
            data=app.audiovoice.Value*data;
            app.audioTrack = audioplayer(data, app.audioTrack.SampleRate);%更新audioTrack
            app.audioTrack.UserData=data;
            ploteqUIAxes(app,1,app.audioTrack.SampleRate,h,w,0,0);
            plotaudioUIAxes(app,app.audioTrack,1);
            if app.playpause.Value==1
                playFile(app, app.audioTrack , app.pauseTime, 'Play');
            end
        end

        % Value changing function: Q4k
        function Q4kValueChanging(app, event)
            changingValue = event.Value;
            app.pauseTime=playFile(app, app.audioTrack , app.pauseTime, 'Pause');
            [ h , w , Y ] = updateandfilt( app ,  app.audio , 8 ,changingValue);
            data=Y;
            if app.soundChannel==2
                Y_2=app.audioTrack.UserData(:,2);
                data=[Y,Y_2];
            end
            data=app.audiovoice.Value*data;
            app.audioTrack = audioplayer(data, app.audioTrack.SampleRate);%更新audioTrack
            app.audioTrack.UserData=data;
            ploteqUIAxes(app,1,app.audioTrack.SampleRate,h,w,0,0);
            plotaudioUIAxes(app,app.audioTrack,1);
            if app.playpause.Value==1
                playFile(app, app.audioTrack , app.pauseTime, 'Play');
            end
        end

        % Value changing function: Q8k
        function Q8kValueChanging(app, event)
            changingValue = event.Value;
            app.pauseTime=playFile(app, app.audioTrack , app.pauseTime, 'Pause');
            [ h , w , Y ] = updateandfilt( app ,  app.audio , 9 ,changingValue);
            data=Y;
            if app.soundChannel==2
                Y_2=app.audioTrack.UserData(:,2);
                data=[Y,Y_2];
            end
            data=app.audiovoice.Value*data;
            app.audioTrack = audioplayer(data, app.audioTrack.SampleRate);%更新audioTrack
            app.audioTrack.UserData=data;
            ploteqUIAxes(app,1,app.audioTrack.SampleRate,h,w,0,0);
            plotaudioUIAxes(app,app.audioTrack,1);
            if app.playpause.Value==1
                playFile(app, app.audioTrack , app.pauseTime, 'Play');
            end
        end

        % Value changing function: Q16k
        function Q16kValueChanging(app, event)
            changingValue = event.Value;
            app.pauseTime=playFile(app, app.audioTrack , app.pauseTime, 'Pause');
            [ h , w , Y ] = updateandfilt( app ,  app.audio , 10 ,changingValue);
            data=Y;
            if app.soundChannel==2
                Y_2=app.audioTrack.UserData(:,2);
                data=[Y,Y_2];
            end
            data=app.audiovoice.Value*data;
            app.audioTrack = audioplayer(data, app.audioTrack.SampleRate);%更新audioTrack
            app.audioTrack.UserData=data;
            ploteqUIAxes(app,1,app.audioTrack.SampleRate,h,w,0,0);
            plotaudioUIAxes(app,app.audioTrack,1);
            if app.playpause.Value==1
                playFile(app, app.audioTrack , app.pauseTime, 'Play');
            end
        end

        % Value changing function: timeBar
        function timeBarValueChanging(app, event)
            changingValue = event.Value;
            %如果正在播放则不执行
            if app.playpause.Value==1
                return
            end
            %更新时域图像
            totalTime = app.audioTrack.TotalSamples / app.audioTrack.SampleRate;
            app.pauseTime = totalTime * changingValue; %更新暂停时间
            axis(app.timeUIAxes,[app.pauseTime-2,app.pauseTime+2,-1,1]);
            xticks(app.timeUIAxes,app.pauseTime);
            xticklabels(app.timeUIAxes,{''});
            if app.audioTrack.NumberOfChannels==2
                axis(app.timeUIAxes_2,[app.pauseTime-2,app.pauseTime+2,-1,1]);
                xticks(app.timeUIAxes_2,app.pauseTime);
                xticklabels(app.timeUIAxes_2,{''});
            end
        end

        % Button pushed function: synchronousButton
        function synchronousButtonPushed(app, event)
            set(app.Freq31,'Value',app.Freq31_2.Value);
            set(app.Freq62,'Value',app.Freq62_2.Value);
            set(app.Freq125,'Value',app.Freq125_2.Value);
            set(app.Freq250,'Value',app.Freq250_2.Value);
            set(app.Freq500,'Value',app.Freq500_2.Value);
            set(app.Freq1k,'Value',app.Freq1k_2.Value);
            set(app.Freq2k,'Value',app.Freq2k_2.Value);
            set(app.Freq4k,'Value',app.Freq4k_2.Value);
            set(app.Freq8k,'Value',app.Freq8k_2.Value);
            set(app.Freq16k,'Value',app.Freq16k_2.Value);
            
            updateFreqtext(app,1);
            updateFreqtext(app,2);
            updateFreqtext(app,3);
            updateFreqtext(app,4);
            updateFreqtext(app,5);
            updateFreqtext(app,6);
            updateFreqtext(app,7);
            updateFreqtext(app,8);
            updateFreqtext(app,9);
            updateFreqtext(app,10);
            
            set(app.Q31,'Value',app.Q31_2.Value);
            set(app.Q62,'Value',app.Q62_2.Value);
            set(app.Q125,'Value',app.Q125_2.Value);
            set(app.Q250,'Value',app.Q250_2.Value);
            set(app.Q500,'Value',app.Q500_2.Value);
            set(app.Q1k,'Value',app.Q1k_2.Value);
            set(app.Q2k,'Value',app.Q2k_2.Value);
            set(app.Q4k,'Value',app.Q4k_2.Value);
            set(app.Q8k,'Value',app.Q8k_2.Value);
            set(app.Q16k,'Value',app.Q16k_2.Value);
            app.pauseTime=playFile(app, app.audioTrack , app.pauseTime, 'Pause');
            [ h , w , Y ] = updateandfilt( app ,  app.audio , 0 ,0);
            data=Y;
            if app.soundChannel==2
                Y_2=app.audioTrack.UserData(:,2);
                data=[Y,Y_2];
            end
            data=app.audiovoice.Value*data;
            app.audioTrack = audioplayer(data, app.audioTrack.SampleRate);%更新audioTrack
            app.audioTrack.UserData=data;
            ploteqUIAxes(app,1,app.audioTrack.SampleRate,h,w,0,0);
            plotaudioUIAxes(app,app.audioTrack,1);
            if app.playpause.Value==1
                playFile(app, app.audioTrack , app.pauseTime, 'Play');
            end
        end

        % Button pushed function: synchronousButton_2
        function synchronousButton_2Pushed(app, event)
            set(app.Freq31_2,'Value',app.Freq31.Value);
            set(app.Freq62_2,'Value',app.Freq62.Value);
            set(app.Freq125_2,'Value',app.Freq125.Value);
            set(app.Freq250_2,'Value',app.Freq250.Value);
            set(app.Freq500_2,'Value',app.Freq500.Value);
            set(app.Freq1k_2,'Value',app.Freq1k.Value);
            set(app.Freq2k_2,'Value',app.Freq2k.Value);
            set(app.Freq4k_2,'Value',app.Freq4k.Value);
            set(app.Freq8k_2,'Value',app.Freq8k.Value);
            set(app.Freq16k_2,'Value',app.Freq16k.Value);
            
            updateFreqtext(app,1);
            updateFreqtext(app,2);
            updateFreqtext(app,3);
            updateFreqtext(app,4);
            updateFreqtext(app,5);
            updateFreqtext(app,6);
            updateFreqtext(app,7);
            updateFreqtext(app,8);
            updateFreqtext(app,9);
            updateFreqtext(app,10);
            
            set(app.Q31_2,'Value',app.Q31.Value);
            set(app.Q62_2,'Value',app.Q62.Value);
            set(app.Q125_2,'Value',app.Q125.Value);
            set(app.Q250_2,'Value',app.Q250.Value);
            set(app.Q500_2,'Value',app.Q500.Value);
            set(app.Q1k_2,'Value',app.Q1k.Value);
            set(app.Q2k_2,'Value',app.Q2k.Value);
            set(app.Q4k_2,'Value',app.Q4k.Value);
            set(app.Q8k_2,'Value',app.Q8k.Value);
            set(app.Q16k_2,'Value',app.Q16k.Value);
            app.pauseTime=playFile(app, app.audioTrack , app.pauseTime, 'Pause');
            [ h_2 , w_2 , Y_2 ] = updateandfilt_2( app , app.audio , 0 ,0);
            Y=app.audioTrack.UserData(:,1);
            data=[Y,Y_2];
            data=app.audiovoice.Value*data;
            app.audioTrack = audioplayer(data, app.audioTrack.SampleRate);%更新audioTrack
            app.audioTrack.UserData=data;
            ploteqUIAxes(app,2,app.audioTrack.SampleRate,0,0,h_2,w_2);
            plotaudioUIAxes(app,app.audioTrack,2);
            if app.playpause.Value==1
                playFile(app, app.audioTrack , app.pauseTime, 'Play');
            end
        end

        % Value changed function: audiovoice
        function audiovoiceValueChanged(app, event)
            value = app.audiovoice.Value;
            double=value/app.oldvoice;
            app.oldvoice=value;
            app.pauseTime=playFile(app, app.audioTrack , app.pauseTime, 'Pause');
            data=app.audioTrack.UserData;
            data=double.*data;
            app.audioTrack = audioplayer(data, app.audioTrack.SampleRate);%更新audioTrack
            app.audioTrack.UserData=data;
            plotaudioUIAxes(app,app.audioTrack,1);
            if app.soundChannel==2
                plotaudioUIAxes(app,app.audioTrack,2);
            end
            if app.playpause.Value==1
                playFile(app,app.audioTrack,app.pauseTime,'Play');
            end
        end

        % Menu selected function: HzMenu2k
        function HzMenu2kSelected(app, event)
            if (strcmp(get(app.audioName, 'Text'), 'No File Loaded') == 1) %音频未加载则不执行
                return
            end
            n=app.audioTrack.TotalSamples;
            fs=app.audioTrack.SampleRate;
            f=(n-1)*fs/n;%最大真实频率
            data=app.audioTrack.UserData;
            Audio_f = abs( fft( data(:,1),n ) );
            if strcmp(get(app.HzMenu2k, 'Checked'), 'on') == 1
                set(app.HzMenu2k,'Checked','off');
                axis(app.frequencyUIAxes,[0,f/2,0,max(abs(Audio_f))+100]);
                axis(app.eqUIAxes,[0,f/2,-12,12]);
                if app.soundChannel==2
                    Audio_f_2 = abs( fft( data(:,2),n ) );
                    axis(app.frequencyUIAxes_2,[0,f/2,0,max(abs(Audio_f_2))+100]);
                    axis(app.eqUIAxes_2,[0,f/2,-12,12]);
                end
            elseif strcmp(get(app.HzMenu2k, 'Checked'), 'off') == 1
                set(app.HzMenu2k,'Checked','on');
                axis(app.frequencyUIAxes,[0,2000,0,max(abs(Audio_f))+100]);
                axis(app.eqUIAxes,[0,2000,-12,12]);
                if app.soundChannel==2
                    Audio_f_2 = abs( fft( data(:,2),n ) );
                    axis(app.frequencyUIAxes_2,[0,2000,0,max(abs(Audio_f_2))+100]);
                    axis(app.eqUIAxes_2,[0,2000,-12,12]);
                end
            end
        end

        % Value changed function: Freq31
        function Freq31ValueChanged(app, event)
            value = app.Freq31.Value;
            app.pauseTime=playFile(app, app.audioTrack , app.pauseTime, 'Pause');
            updateFreqtext(app,1);
            [ h , w , Y ] = updateandfilt( app ,  app.audio , 0 ,value);
            data=Y;
            if app.soundChannel==2
                Y_2=app.audioTrack.UserData(:,2);
                data=[Y,Y_2];
            end
            data=app.audiovoice.Value*data;
            app.audioTrack = audioplayer(data, app.audioTrack.SampleRate);%更新audioTrack
            app.audioTrack.UserData=data;
            ploteqUIAxes(app,1,app.audioTrack.SampleRate,h,w,0,0);
            plotaudioUIAxes(app,app.audioTrack,1);
            if app.playpause.Value==1
                playFile(app, app.audioTrack , app.pauseTime, 'Play');
            end
        end

        % Value changed function: Freq62
        function Freq62ValueChanged(app, event)
            value = app.Freq62.Value;
            app.pauseTime=playFile(app, app.audioTrack , app.pauseTime, 'Pause');
            updateFreqtext(app,2);
            [ h , w , Y ] = updateandfilt( app ,  app.audio , 0 ,value);
            data=Y;
            if app.soundChannel==2
                Y_2=app.audioTrack.UserData(:,2);
                data=[Y,Y_2];
            end
            data=app.audiovoice.Value*data;
            app.audioTrack = audioplayer(data, app.audioTrack.SampleRate);%更新audioTrack
            app.audioTrack.UserData=data;
            ploteqUIAxes(app,1,app.audioTrack.SampleRate,h,w,0,0);
            plotaudioUIAxes(app,app.audioTrack,1);
            if app.playpause.Value==1
                playFile(app, app.audioTrack , app.pauseTime, 'Play');
            end
        end

        % Value changed function: Freq125
        function Freq125ValueChanged(app, event)
            value = app.Freq125.Value;
            app.pauseTime=playFile(app, app.audioTrack , app.pauseTime, 'Pause');
            updateFreqtext(app,3);
            [ h , w , Y ] = updateandfilt( app ,  app.audio , 0 ,value);
            data=Y;
            if app.soundChannel==2
                Y_2=app.audioTrack.UserData(:,2);
                data=[Y,Y_2];
            end
            data=app.audiovoice.Value*data;
            app.audioTrack = audioplayer(data, app.audioTrack.SampleRate);%更新audioTrack
            app.audioTrack.UserData=data;
            ploteqUIAxes(app,1,app.audioTrack.SampleRate,h,w,0,0);
            plotaudioUIAxes(app,app.audioTrack,1);
            if app.playpause.Value==1
                playFile(app, app.audioTrack , app.pauseTime, 'Play');
            end
        end

        % Value changed function: Freq250
        function Freq250ValueChanged(app, event)
            value = app.Freq250.Value;
            app.pauseTime=playFile(app, app.audioTrack , app.pauseTime, 'Pause');
            updateFreqtext(app,4);
            [ h , w , Y ] = updateandfilt( app ,  app.audio , 0 ,value);
            data=Y;
            if app.soundChannel==2
                Y_2=app.audioTrack.UserData(:,2);
                data=[Y,Y_2];
            end
            data=app.audiovoice.Value*data;
            app.audioTrack = audioplayer(data, app.audioTrack.SampleRate);%更新audioTrack
            app.audioTrack.UserData=data;
            ploteqUIAxes(app,1,app.audioTrack.SampleRate,h,w,0,0);
            plotaudioUIAxes(app,app.audioTrack,1);
            if app.playpause.Value==1
                playFile(app, app.audioTrack , app.pauseTime, 'Play');
            end
        end

        % Value changing function: Q31
        function Q31ValueChanging(app, event)
            changingValue = event.Value;
            app.pauseTime=playFile(app, app.audioTrack , app.pauseTime, 'Pause');
            [ h , w , Y ] = updateandfilt( app ,  app.audio , 1 ,changingValue);
            data=Y;
            if app.soundChannel==2
                Y_2=app.audioTrack.UserData(:,2);
                data=[Y,Y_2];
            end
            data=app.audiovoice.Value*data;
            app.audioTrack = audioplayer(data, app.audioTrack.SampleRate);%更新audioTrack
            app.audioTrack.UserData=data;
            ploteqUIAxes(app,1,app.audioTrack.SampleRate,h,w,0,0);
            plotaudioUIAxes(app,app.audioTrack,1);
            if app.playpause.Value==1
                playFile(app, app.audioTrack , app.pauseTime, 'Play');
            end
        end

        % Value changing function: Q62
        function Q62ValueChanging(app, event)
            changingValue = event.Value;
            app.pauseTime=playFile(app, app.audioTrack , app.pauseTime, 'Pause');
            [ h , w , Y ] = updateandfilt( app ,  app.audio , 2 ,changingValue);
            data=Y;
            if app.soundChannel==2
                Y_2=app.audioTrack.UserData(:,2);
                data=[Y,Y_2];
            end
            data=app.audiovoice.Value*data;
            app.audioTrack = audioplayer(data, app.audioTrack.SampleRate);%更新audioTrack
            app.audioTrack.UserData=data;
            ploteqUIAxes(app,1,app.audioTrack.SampleRate,h,w,0,0);
            plotaudioUIAxes(app,app.audioTrack,1);
            if app.playpause.Value==1
                playFile(app, app.audioTrack , app.pauseTime, 'Play');
            end
        end

        % Value changing function: Q125
        function Q125ValueChanging(app, event)
            changingValue = event.Value;
            app.pauseTime=playFile(app, app.audioTrack , app.pauseTime, 'Pause');
            [ h , w , Y ] = updateandfilt( app ,  app.audio , 3 ,changingValue);
            data=Y;
            if app.soundChannel==2
                Y_2=app.audioTrack.UserData(:,2);
                data=[Y,Y_2];
            end
            data=app.audiovoice.Value*data;
            app.audioTrack = audioplayer(data, app.audioTrack.SampleRate);%更新audioTrack
            app.audioTrack.UserData=data;
            ploteqUIAxes(app,1,app.audioTrack.SampleRate,h,w,0,0);
            plotaudioUIAxes(app,app.audioTrack,1);
            if app.playpause.Value==1
                playFile(app, app.audioTrack , app.pauseTime, 'Play');
            end
        end

        % Value changing function: Q250
        function Q250ValueChanging(app, event)
            changingValue = event.Value;
            app.pauseTime=playFile(app, app.audioTrack , app.pauseTime, 'Pause');
            [ h , w , Y ] = updateandfilt( app ,  app.audio , 4 ,changingValue);
            data=Y;
            if app.soundChannel==2
                Y_2=app.audioTrack.UserData(:,2);
                data=[Y,Y_2];
            end
            data=app.audiovoice.Value*data;
            app.audioTrack = audioplayer(data, app.audioTrack.SampleRate);%更新audioTrack
            app.audioTrack.UserData=data;
            ploteqUIAxes(app,1,app.audioTrack.SampleRate,h,w,0,0);
            plotaudioUIAxes(app,app.audioTrack,1);
            if app.playpause.Value==1
                playFile(app, app.audioTrack , app.pauseTime, 'Play');
            end
        end

        % Value changed function: Freq31_2
        function Freq31_2ValueChanged(app, event)
            value = app.Freq31_2.Value;
            app.pauseTime=playFile(app, app.audioTrack , app.pauseTime, 'Pause');
            updateFreqtext(app,1);
            [ h_2 , w_2 , Y_2 ] = updateandfilt_2( app , app.audio , 0 ,value);
            Y=app.audioTrack.UserData(:,1);
            data=[Y,Y_2];
            data=app.audiovoice.Value*data;
            app.audioTrack = audioplayer(data, app.audioTrack.SampleRate);%更新audioTrack
            app.audioTrack.UserData=data;
            ploteqUIAxes(app,2,app.audioTrack.SampleRate,0,0,h_2,w_2);
            plotaudioUIAxes(app,app.audioTrack,2);
            if app.playpause.Value==1
                playFile(app, app.audioTrack , app.pauseTime, 'Play');
            end
        end

        % Value changed function: Freq62_2
        function Freq62_2ValueChanged(app, event)
            value = app.Freq62_2.Value;
            app.pauseTime=playFile(app, app.audioTrack , app.pauseTime, 'Pause');
            updateFreqtext(app,2);
            [ h_2 , w_2 , Y_2 ] = updateandfilt_2( app , app.audio , 0 ,value);
            Y=app.audioTrack.UserData(:,1);
            data=[Y,Y_2];
            data=app.audiovoice.Value*data;
            app.audioTrack = audioplayer(data, app.audioTrack.SampleRate);%更新audioTrack
            app.audioTrack.UserData=data;
            ploteqUIAxes(app,2,app.audioTrack.SampleRate,0,0,h_2,w_2);
            plotaudioUIAxes(app,app.audioTrack,2);
            if app.playpause.Value==1
                playFile(app, app.audioTrack , app.pauseTime, 'Play');
            end
        end

        % Value changed function: Freq125_2
        function Freq125_2ValueChanged(app, event)
            value = app.Freq125_2.Value;
            app.pauseTime=playFile(app, app.audioTrack , app.pauseTime, 'Pause');
            updateFreqtext(app,3);
            [ h_2 , w_2 , Y_2 ] = updateandfilt_2( app , app.audio , 0 ,value);
            Y=app.audioTrack.UserData(:,1);
            data=[Y,Y_2];
            data=app.audiovoice.Value*data;
            app.audioTrack = audioplayer(data, app.audioTrack.SampleRate);%更新audioTrack
            app.audioTrack.UserData=data;
            ploteqUIAxes(app,2,app.audioTrack.SampleRate,0,0,h_2,w_2);
            plotaudioUIAxes(app,app.audioTrack,2);
            if app.playpause.Value==1
                playFile(app, app.audioTrack , app.pauseTime, 'Play');
            end
        end

        % Value changed function: Freq250_2
        function Freq250_2ValueChanged(app, event)
            value = app.Freq250_2.Value;
            app.pauseTime=playFile(app, app.audioTrack , app.pauseTime, 'Pause');
            updateFreqtext(app,4);
            [ h_2 , w_2 , Y_2 ] = updateandfilt_2( app , app.audio , 0 ,value);
            Y=app.audioTrack.UserData(:,1);
            data=[Y,Y_2];
            data=app.audiovoice.Value*data;
            app.audioTrack = audioplayer(data, app.audioTrack.SampleRate);%更新audioTrack
            app.audioTrack.UserData=data;
            ploteqUIAxes(app,2,app.audioTrack.SampleRate,0,0,h_2,w_2);
            plotaudioUIAxes(app,app.audioTrack,2);
            if app.playpause.Value==1
                playFile(app, app.audioTrack , app.pauseTime, 'Play');
            end
        end

        % Value changing function: Q31_2
        function Q31_2ValueChanging(app, event)
            changingValue = event.Value;
            app.pauseTime=playFile(app, app.audioTrack , app.pauseTime, 'Pause');
            [ h_2 , w_2 , Y_2 ] = updateandfilt_2( app , app.audio , 1 ,changingValue);
            Y=app.audioTrack.UserData(:,1);
            data=[Y,Y_2];
            data=app.audiovoice.Value*data;
            app.audioTrack = audioplayer(data, app.audioTrack.SampleRate);%更新audioTrack
            app.audioTrack.UserData=data;
            ploteqUIAxes(app,2,app.audioTrack.SampleRate,0,0,h_2,w_2);
            plotaudioUIAxes(app,app.audioTrack,2);
            if app.playpause.Value==1
                playFile(app, app.audioTrack , app.pauseTime, 'Play');
            end
        end

        % Value changing function: Q62_2
        function Q62_2ValueChanging(app, event)
            changingValue = event.Value;
            app.pauseTime=playFile(app, app.audioTrack , app.pauseTime, 'Pause');
            [ h_2 , w_2 , Y_2 ] = updateandfilt_2( app , app.audio , 2 ,changingValue);
            Y=app.audioTrack.UserData(:,1);
            data=[Y,Y_2];
            data=app.audiovoice.Value*data;
            app.audioTrack = audioplayer(data, app.audioTrack.SampleRate);%更新audioTrack
            app.audioTrack.UserData=data;
            ploteqUIAxes(app,2,app.audioTrack.SampleRate,0,0,h_2,w_2);
            plotaudioUIAxes(app,app.audioTrack,2);
            if app.playpause.Value==1
                playFile(app, app.audioTrack , app.pauseTime, 'Play');
            end
        end

        % Value changing function: Q125_2
        function Q125_2ValueChanging(app, event)
            changingValue = event.Value;
            app.pauseTime=playFile(app, app.audioTrack , app.pauseTime, 'Pause');
            [ h_2 , w_2 , Y_2 ] = updateandfilt_2( app , app.audio , 3 ,changingValue);
            Y=app.audioTrack.UserData(:,1);
            data=[Y,Y_2];
            data=app.audiovoice.Value*data;
            app.audioTrack = audioplayer(data, app.audioTrack.SampleRate);%更新audioTrack
            app.audioTrack.UserData=data;
            ploteqUIAxes(app,2,app.audioTrack.SampleRate,0,0,h_2,w_2);
            plotaudioUIAxes(app,app.audioTrack,2);
            if app.playpause.Value==1
                playFile(app, app.audioTrack , app.pauseTime, 'Play');
            end
        end

        % Value changing function: Q250_2
        function Q250_2ValueChanging(app, event)
            changingValue = event.Value;
            app.pauseTime=playFile(app, app.audioTrack , app.pauseTime, 'Pause');
            [ h_2 , w_2 , Y_2 ] = updateandfilt_2( app , app.audio , 4 ,changingValue);
            Y=app.audioTrack.UserData(:,1);
            data=[Y,Y_2];
            data=app.audiovoice.Value*data;
            app.audioTrack = audioplayer(data, app.audioTrack.SampleRate);%更新audioTrack
            app.audioTrack.UserData=data;
            ploteqUIAxes(app,2,app.audioTrack.SampleRate,0,0,h_2,w_2);
            plotaudioUIAxes(app,app.audioTrack,2);
            if app.playpause.Value==1
                playFile(app, app.audioTrack , app.pauseTime, 'Play');
            end
        end

        % Value changed function: Freq500_2
        function Freq500_2ValueChanged(app, event)
            value = app.Freq500_2.Value;
            app.pauseTime=playFile(app, app.audioTrack , app.pauseTime, 'Pause');
            updateFreqtext(app,5);
            [ h_2 , w_2 , Y_2 ] = updateandfilt_2( app , app.audio , 0 ,value);
            Y=app.audioTrack.UserData(:,1);
            data=[Y,Y_2];
            data=app.audiovoice.Value*data;
            app.audioTrack = audioplayer(data, app.audioTrack.SampleRate);%更新audioTrack
            app.audioTrack.UserData=data;
            ploteqUIAxes(app,2,app.audioTrack.SampleRate,0,0,h_2,w_2);
            plotaudioUIAxes(app,app.audioTrack,2);
            if app.playpause.Value==1
                playFile(app, app.audioTrack , app.pauseTime, 'Play');
            end
        end

        % Value changed function: Freq1k_2
        function Freq1k_2ValueChanged(app, event)
            value = app.Freq1k_2.Value;
            app.pauseTime=playFile(app, app.audioTrack , app.pauseTime, 'Pause');
            updateFreqtext(app,6);
            [ h_2 , w_2 , Y_2 ] = updateandfilt_2( app , app.audio , 0 ,value);
            Y=app.audioTrack.UserData(:,1);
            data=[Y,Y_2];
            data=app.audiovoice.Value*data;
            app.audioTrack = audioplayer(data, app.audioTrack.SampleRate);%更新audioTrack
            app.audioTrack.UserData=data;
            ploteqUIAxes(app,2,app.audioTrack.SampleRate,0,0,h_2,w_2);
            plotaudioUIAxes(app,app.audioTrack,2);
            if app.playpause.Value==1
                playFile(app, app.audioTrack , app.pauseTime, 'Play');
            end
        end

        % Value changed function: Freq2k_2
        function Freq2k_2ValueChanged(app, event)
            value = app.Freq2k_2.Value;
            app.pauseTime=playFile(app, app.audioTrack , app.pauseTime, 'Pause');
            updateFreqtext(app,7);
            [ h_2 , w_2 , Y_2 ] = updateandfilt_2( app , app.audio , 0 ,value);
            Y=app.audioTrack.UserData(:,1);
            data=[Y,Y_2];
            data=app.audiovoice.Value*data;
            app.audioTrack = audioplayer(data, app.audioTrack.SampleRate);%更新audioTrack
            app.audioTrack.UserData=data;
            ploteqUIAxes(app,2,app.audioTrack.SampleRate,0,0,h_2,w_2);
            plotaudioUIAxes(app,app.audioTrack,2);
            if app.playpause.Value==1
                playFile(app, app.audioTrack , app.pauseTime, 'Play');
            end
        end

        % Value changed function: Freq4k_2
        function Freq4k_2ValueChanged(app, event)
            value = app.Freq4k_2.Value;
            app.pauseTime=playFile(app, app.audioTrack , app.pauseTime, 'Pause');
            updateFreqtext(app,8);
            [ h_2 , w_2 , Y_2 ] = updateandfilt_2( app , app.audio , 0 ,value);
            Y=app.audioTrack.UserData(:,1);
            data=[Y,Y_2];
            data=app.audiovoice.Value*data;
            app.audioTrack = audioplayer(data, app.audioTrack.SampleRate);%更新audioTrack
            app.audioTrack.UserData=data;
            ploteqUIAxes(app,2,app.audioTrack.SampleRate,0,0,h_2,w_2);
            plotaudioUIAxes(app,app.audioTrack,2);
            if app.playpause.Value==1
                playFile(app, app.audioTrack , app.pauseTime, 'Play');
            end
        end

        % Value changed function: Freq8k_2
        function Freq8k_2ValueChanged(app, event)
            value = app.Freq8k_2.Value;
            app.pauseTime=playFile(app, app.audioTrack , app.pauseTime, 'Pause');
            updateFreqtext(app,9);
            [ h_2 , w_2 , Y_2 ] = updateandfilt_2( app , app.audio , 0 ,value);
            Y=app.audioTrack.UserData(:,1);
            data=[Y,Y_2];
            data=app.audiovoice.Value*data;
            app.audioTrack = audioplayer(data, app.audioTrack.SampleRate);%更新audioTrack
            app.audioTrack.UserData=data;
            ploteqUIAxes(app,2,app.audioTrack.SampleRate,0,0,h_2,w_2);
            plotaudioUIAxes(app,app.audioTrack,2);
            if app.playpause.Value==1
                playFile(app, app.audioTrack , app.pauseTime, 'Play');
            end
        end

        % Value changed function: Freq16k_2
        function Freq16k_2ValueChanged(app, event)
            value = app.Freq16k_2.Value;
            app.pauseTime=playFile(app, app.audioTrack , app.pauseTime, 'Pause');
            updateFreqtext(app,10);
            [ h_2 , w_2 , Y_2 ] = updateandfilt_2( app , app.audio , 0 ,value);
            Y=app.audioTrack.UserData(:,1);
            data=[Y,Y_2];
            data=app.audiovoice.Value*data;
            app.audioTrack = audioplayer(data, app.audioTrack.SampleRate);%更新audioTrack
            app.audioTrack.UserData=data;
            ploteqUIAxes(app,2,app.audioTrack.SampleRate,0,0,h_2,w_2);
            plotaudioUIAxes(app,app.audioTrack,2);
            if app.playpause.Value==1
                playFile(app, app.audioTrack , app.pauseTime, 'Play');
            end
        end

        % Value changing function: Q500_2
        function Q500_2ValueChanging(app, event)
            changingValue = event.Value;
            app.pauseTime=playFile(app, app.audioTrack , app.pauseTime, 'Pause');
            [ h_2 , w_2 , Y_2 ] = updateandfilt_2( app , app.audio , 5 ,changingValue);
            Y=app.audioTrack.UserData(:,1);
            data=[Y,Y_2];
            data=app.audiovoice.Value*data;
            app.audioTrack = audioplayer(data, app.audioTrack.SampleRate);%更新audioTrack
            app.audioTrack.UserData=data;
            ploteqUIAxes(app,2,app.audioTrack.SampleRate,0,0,h_2,w_2);
            plotaudioUIAxes(app,app.audioTrack,2);
            if app.playpause.Value==1
                playFile(app, app.audioTrack , app.pauseTime, 'Play');
            end
        end

        % Value changing function: Q1k_2
        function Q1k_2ValueChanging(app, event)
            changingValue = event.Value;
            app.pauseTime=playFile(app, app.audioTrack , app.pauseTime, 'Pause');
            [ h_2 , w_2 , Y_2 ] = updateandfilt_2( app , app.audio , 6 ,changingValue);
            Y=app.audioTrack.UserData(:,1);
            data=[Y,Y_2];
            data=app.audiovoice.Value*data;
            app.audioTrack = audioplayer(data, app.audioTrack.SampleRate);%更新audioTrack
            app.audioTrack.UserData=data;
            ploteqUIAxes(app,2,app.audioTrack.SampleRate,0,0,h_2,w_2);
            plotaudioUIAxes(app,app.audioTrack,2);
            if app.playpause.Value==1
                playFile(app, app.audioTrack , app.pauseTime, 'Play');
            end
        end

        % Value changing function: Q2k_2
        function Q2k_2ValueChanging(app, event)
            changingValue = event.Value;
            app.pauseTime=playFile(app, app.audioTrack , app.pauseTime, 'Pause');
            [ h_2 , w_2 , Y_2 ] = updateandfilt_2( app , app.audio , 7 ,changingValue);
            Y=app.audioTrack.UserData(:,1);
            data=[Y,Y_2];
            data=app.audiovoice.Value*data;
            app.audioTrack = audioplayer(data, app.audioTrack.SampleRate);%更新audioTrack
            app.audioTrack.UserData=data;
            ploteqUIAxes(app,2,app.audioTrack.SampleRate,0,0,h_2,w_2);
            plotaudioUIAxes(app,app.audioTrack,2);
            if app.playpause.Value==1
                playFile(app, app.audioTrack , app.pauseTime, 'Play');
            end
        end

        % Value changing function: Q4k_2
        function Q4k_2ValueChanging(app, event)
            changingValue = event.Value;
            app.pauseTime=playFile(app, app.audioTrack , app.pauseTime, 'Pause');
            [ h_2 , w_2 , Y_2 ] = updateandfilt_2( app , app.audio , 8 ,changingValue);
            Y=app.audioTrack.UserData(:,1);
            data=[Y,Y_2];
            data=app.audiovoice.Value*data;
            app.audioTrack = audioplayer(data, app.audioTrack.SampleRate);%更新audioTrack
            app.audioTrack.UserData=data;
            ploteqUIAxes(app,2,app.audioTrack.SampleRate,0,0,h_2,w_2);
            plotaudioUIAxes(app,app.audioTrack,2);
            if app.playpause.Value==1
                playFile(app, app.audioTrack , app.pauseTime, 'Play');
            end
        end

        % Value changing function: Q8k_2
        function Q8k_2ValueChanging(app, event)
            changingValue = event.Value;
            app.pauseTime=playFile(app, app.audioTrack , app.pauseTime, 'Pause');
            [ h_2 , w_2 , Y_2 ] = updateandfilt_2( app , app.audio , 9 ,changingValue);
            Y=app.audioTrack.UserData(:,1);
            data=[Y,Y_2];
            data=app.audiovoice.Value*data;
            app.audioTrack = audioplayer(data, app.audioTrack.SampleRate);%更新audioTrack
            app.audioTrack.UserData=data;
            ploteqUIAxes(app,2,app.audioTrack.SampleRate,0,0,h_2,w_2);
            plotaudioUIAxes(app,app.audioTrack,2);
            if app.playpause.Value==1
                playFile(app, app.audioTrack , app.pauseTime, 'Play');
            end
        end

        % Value changing function: Q16k_2
        function Q16k_2ValueChanging(app, event)
            changingValue = event.Value;
            app.pauseTime=playFile(app, app.audioTrack , app.pauseTime, 'Pause');
            [ h_2 , w_2 , Y_2 ] = updateandfilt_2( app , app.audio , 10 ,changingValue);
            Y=app.audioTrack.UserData(:,1);
            data=[Y,Y_2];
            data=app.audiovoice.Value*data;
            app.audioTrack = audioplayer(data, app.audioTrack.SampleRate);%更新audioTrack
            app.audioTrack.UserData=data;
            ploteqUIAxes(app,2,app.audioTrack.SampleRate,0,0,h_2,w_2);
            plotaudioUIAxes(app,app.audioTrack,2);
            if app.playpause.Value==1
                playFile(app, app.audioTrack , app.pauseTime, 'Play');
            end
        end

        % Menu selected function: audioLoad
        function audioLoadMenuSelected(app, event)
            if strcmp(get(app.audioName,'Text'),'No File Loaded')==1
                return
            end
            [file,location] = uiputfile('audio.wav');
            fullPathName = strcat(location, file);
            [~,~,ext]=fileparts(app.audioFile.Value);
            if (strcmp(ext,'.wav')==0)&&(strcmp(ext,'')==0)
                msgbox("文件拓展名不正确","错误","error");
                return
            end
            if(ischar(file)) 
                audiowrite(fullPathName,app.audioTrack.UserData,app.audioFs);
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = '文件';
            app.audioLoad = uimenu(app.Menu);
            app.audioLoad.MenuSelectedFcn = createCallbackFcn(app, @audioLoadMenuSelected, true);
            app.audioLoad.Separator = 'on';
            app.audioLoad.Text = '保存';
            app.Menu_2 = uimenu(app.UIFigure);
            app.Menu_2.Text = '视图';
            app.HzMenu2k = uimenu(app.Menu_2);
            app.HzMenu2k.MenuSelectedFcn = createCallbackFcn(app, @HzMenu2kSelected, true);
            app.HzMenu2k.Separator = 'on';
            app.HzMenu2k.Checked = 'on';
            app.HzMenu2k.Text = '0～2kHz';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.soundplayer = uitab(app.TabGroup);
            app.soundplayer.Title = '播放器';
            app.timeUIAxes = uiaxes(app.soundplayer);
            title(app.timeUIAxes, '通道1时域图')
            app.timeUIAxes.XTick = [];
            app.timeUIAxes.YTick = [];
            app.timeBar = uislider(app.soundplayer);
            app.timeBar.Limits = [0 1];
            app.timeBar.MajorTicks = [];
            app.timeBar.MajorTickLabels = {'0'};
            app.timeBar.ValueChangedFcn = createCallbackFcn(app, @timeBarValueChanged, true);
            app.timeBar.ValueChangingFcn = createCallbackFcn(app, @timeBarValueChanging, true);
            app.timeBar.MinorTicks = [];
            app.timeBar.Tooltip = {'1212'; ''};
            app.playpause = uibutton(app.soundplayer, 'state');
            app.playpause.ValueChangedFcn = createCallbackFcn(app, @playpauseValueChanged, true);
            app.playpause.Text = '播放/暂停';
            app.timeElapsed = uilabel(app.soundplayer);
            app.timeElapsed.Text = '00:00:00';
            app.timeLeft = uilabel(app.soundplayer);
            app.timeLeft.Text = '-00:00:00';
            app.audioName = uilabel(app.soundplayer);
            app.audioName.Text = 'No File Loaded';
            app.audioFile = uieditfield(app.soundplayer, 'text');
            app.choose = uibutton(app.soundplayer, 'push');
            app.choose.ButtonPushedFcn = createCallbackFcn(app, @chooseButtonPushed, true);
            app.choose.Text = '从文件夹中选择';
            app.load = uibutton(app.soundplayer, 'push');
            app.load.ButtonPushedFcn = createCallbackFcn(app, @loadButtonPushed, true);
            app.load.Text = '加载音频文件';
            app.Label_2 = uilabel(app.soundplayer);
            app.Label_2.Text = '音量';
            app.audiovoice = uislider(app.soundplayer);
            app.audiovoice.Limits = [0.1 2];
            app.audiovoice.MajorTicks = [];
            app.audiovoice.ValueChangedFcn = createCallbackFcn(app, @audiovoiceValueChanged, true);
            app.audiovoice.ValueChangingFcn = createCallbackFcn(app, @audiovoiceValueChanging, true);
            app.audiovoice.MinorTicks = [];
            app.audiovoice.Value = 1;
            app.audiovoice_edit = uilabel(app.soundplayer);
            app.audiovoice_edit.Text = '50';
            app.timeUIAxes_2 = uiaxes(app.soundplayer);
            title(app.timeUIAxes_2, '通道2时域图')
            app.timeUIAxes_2.XTick = [];
            app.timeUIAxes_2.YTick = [];
            app.respones = uitab(app.TabGroup);
            app.respones.Title = '通道1均衡器幅频响应';
            app.eqUIAxes = uiaxes(app.respones);
            title(app.eqUIAxes, '通道1滤波器频率响应')
            app.frequencyUIAxes = uiaxes(app.respones);
            title(app.frequencyUIAxes, '通道1频域图')
            app.Tab_3 = uitab(app.TabGroup);
            app.Tab_3.Title = '通道2均衡器幅频响应';
            app.eqUIAxes_2 = uiaxes(app.Tab_3);
            title(app.eqUIAxes_2, '通道2滤波器频率响应')
            app.frequencyUIAxes_2 = uiaxes(app.Tab_3);
            title(app.frequencyUIAxes_2, '通道2频域图')
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup2);
            app.Tab.Title = '通道1';
            app.Freq500text = uilabel(app.Tab);
            app.Freq500text.Text = '500Hz';
            app.Freq500 = uislider(app.Tab);
            app.Freq500.Limits = [-12 12];
            app.Freq500.MajorTicks = [];
            app.Freq500.ValueChangedFcn = createCallbackFcn(app, @Freq500ValueChanged, true);
            app.Freq500.MinorTicks = [];
            app.Freq500.Enable = 'off';
            app.dbLabel500 = uilabel(app.Tab);
            app.dbLabel500.Text = '0db';
            app.Spinner_5Label = uilabel(app.Tab);
            app.Spinner_5Label.Text = '品质因数';
            app.Q500 = uispinner(app.Tab);
            app.Q500.Step = 0.1;
            app.Q500.ValueChangingFcn = createCallbackFcn(app, @Q500ValueChanging, true);
            app.Q500.Limits = [0.1 50];
            app.Q500.ValueDisplayFormat = '%.2f';
            app.Q500.Editable = 'off';
            app.Q500.Enable = 'off';
            app.Q500.Value = 1;
            app.Freq1ktext = uilabel(app.Tab);
            app.Freq1ktext.Text = '1kHz';
            app.Freq1k = uislider(app.Tab);
            app.Freq1k.Limits = [-12 12];
            app.Freq1k.MajorTicks = [];
            app.Freq1k.ValueChangedFcn = createCallbackFcn(app, @Freq1kValueChanged, true);
            app.Freq1k.MinorTicks = [];
            app.Freq1k.Enable = 'off';
            app.dbLabel1k = uilabel(app.Tab);
            app.dbLabel1k.Text = '0db';
            app.Spinner_6Label = uilabel(app.Tab);
            app.Spinner_6Label.Text = '品质因数';
            app.Q1k = uispinner(app.Tab);
            app.Q1k.Step = 0.1;
            app.Q1k.ValueChangingFcn = createCallbackFcn(app, @Q1kValueChanging, true);
            app.Q1k.Limits = [0.1 50];
            app.Q1k.ValueDisplayFormat = '%.2f';
            app.Q1k.Editable = 'off';
            app.Q1k.Enable = 'off';
            app.Q1k.Value = 1;
            app.Freq2ktext = uilabel(app.Tab);
            app.Freq2ktext.Text = '2kHz';
            app.Freq2k = uislider(app.Tab);
            app.Freq2k.Limits = [-12 12];
            app.Freq2k.MajorTicks = [];
            app.Freq2k.ValueChangedFcn = createCallbackFcn(app, @Freq2kValueChanged, true);
            app.Freq2k.MinorTicks = [];
            app.Freq2k.Enable = 'off';
            app.dbLabel2k = uilabel(app.Tab);
            app.dbLabel2k.Text = '0db';
            app.Spinner_7Label = uilabel(app.Tab);
            app.Spinner_7Label.Text = '品质因数';
            app.Q2k = uispinner(app.Tab);
            app.Q2k.Step = 0.1;
            app.Q2k.ValueChangingFcn = createCallbackFcn(app, @Q2kValueChanging, true);
            app.Q2k.Limits = [0.1 50];
            app.Q2k.ValueDisplayFormat = '%.2f';
            app.Q2k.Editable = 'off';
            app.Q2k.Enable = 'off';
            app.Q2k.Value = 1;
            app.Freq4ktext = uilabel(app.Tab);
            app.Freq4ktext.Text = '4kHz';
            app.Freq4k = uislider(app.Tab);
            app.Freq4k.Limits = [-12 12];
            app.Freq4k.MajorTicks = [];
            app.Freq4k.ValueChangedFcn = createCallbackFcn(app, @Freq4kValueChanged, true);
            app.Freq4k.MinorTicks = [];
            app.Freq4k.Enable = 'off';
            app.dbLabel4k = uilabel(app.Tab);
            app.dbLabel4k.Text = '0db';
            app.Spinner_8Label = uilabel(app.Tab);
            app.Spinner_8Label.Text = '品质因数';
            app.Q4k = uispinner(app.Tab);
            app.Q4k.Step = 0.1;
            app.Q4k.ValueChangingFcn = createCallbackFcn(app, @Q4kValueChanging, true);
            app.Q4k.Limits = [0.1 50];
            app.Q4k.ValueDisplayFormat = '%.2f';
            app.Q4k.Editable = 'off';
            app.Q4k.Enable = 'off';
            app.Q4k.Value = 1;
            app.Freq8ktext = uilabel(app.Tab);
            app.Freq8ktext.Text = '8kHz';
            app.Freq8k = uislider(app.Tab);
            app.Freq8k.Limits = [-12 12];
            app.Freq8k.MajorTicks = [];
            app.Freq8k.ValueChangedFcn = createCallbackFcn(app, @Freq8kValueChanged, true);
            app.Freq8k.MinorTicks = [];
            app.Freq8k.Enable = 'off';
            app.dbLabel8k = uilabel(app.Tab);
            app.dbLabel8k.Text = '0db';
            app.Spinner_9Label = uilabel(app.Tab);
            app.Spinner_9Label.Text = '品质因数';
            app.Q8k = uispinner(app.Tab);
            app.Q8k.Step = 0.1;
            app.Q8k.ValueChangingFcn = createCallbackFcn(app, @Q8kValueChanging, true);
            app.Q8k.Limits = [0.1 50];
            app.Q8k.ValueDisplayFormat = '%.2f';
            app.Q8k.Editable = 'off';
            app.Q8k.Enable = 'off';
            app.Q8k.Value = 1;
            app.Freq16ktext = uilabel(app.Tab);
            app.Freq16ktext.Text = '16kHz';
            app.Freq16k = uislider(app.Tab);
            app.Freq16k.Limits = [-12 12];
            app.Freq16k.MajorTicks = [];
            app.Freq16k.ValueChangedFcn = createCallbackFcn(app, @Freq16kValueChanged, true);
            app.Freq16k.MinorTicks = [];
            app.Freq16k.Enable = 'off';
            app.dbLabel16k = uilabel(app.Tab);
            app.dbLabel16k.Text = '0db';
            app.Spinner_10Label = uilabel(app.Tab);
            app.Spinner_10Label.Text = '品质因数';
            app.Q16k = uispinner(app.Tab);
            app.Q16k.Step = 0.1;
            app.Q16k.ValueChangingFcn = createCallbackFcn(app, @Q16kValueChanging, true);
            app.Q16k.Limits = [0.1 50];
            app.Q16k.ValueDisplayFormat = '%.2f';
            app.Q16k.Editable = 'off';
            app.Q16k.Enable = 'off';
            app.Q16k.Value = 1;
            app.synchronousButton = uibutton(app.Tab, 'push');
            app.synchronousButton.ButtonPushedFcn = createCallbackFcn(app, @synchronousButtonPushed, true);
            app.synchronousButton.Enable = 'off';
            app.synchronousButton.Text = '同步通道2';
            app.Freq250text = uilabel(app.Tab);
            app.Freq250text.Text = '250Hz';
            app.Freq250 = uislider(app.Tab);
            app.Freq250.Limits = [-12 12];
            app.Freq250.MajorTicks = [];
            app.Freq250.ValueChangedFcn = createCallbackFcn(app, @Freq250ValueChanged, true);
            app.Freq250.MinorTicks = [];
            app.Freq250.Enable = 'off';
            app.dbLabel250 = uilabel(app.Tab);
            app.dbLabel250.Text = '0db';
            app.Spinner_5Label_3 = uilabel(app.Tab);
            app.Spinner_5Label_3.Text = '品质因数';
            app.Q250 = uispinner(app.Tab);
            app.Q250.Step = 0.1;
            app.Q250.ValueChangingFcn = createCallbackFcn(app, @Q250ValueChanging, true);
            app.Q250.Limits = [0.1 50];
            app.Q250.ValueDisplayFormat = '%.2f';
            app.Q250.Editable = 'off';
            app.Q250.Enable = 'off';
            app.Q250.Value = 1;
            app.Freq125text = uilabel(app.Tab);
            app.Freq125text.Text = '125Hz';
            app.Freq125 = uislider(app.Tab);
            app.Freq125.Limits = [-12 12];
            app.Freq125.MajorTicks = [];
            app.Freq125.ValueChangedFcn = createCallbackFcn(app, @Freq125ValueChanged, true);
            app.Freq125.MinorTicks = [];
            app.Freq125.Enable = 'off';
            app.dbLabel125 = uilabel(app.Tab);
            app.dbLabel125.Text = '0db';
            app.Spinner_5Label_4 = uilabel(app.Tab);
            app.Spinner_5Label_4.Text = '品质因数';
            app.Q125 = uispinner(app.Tab);
            app.Q125.Step = 0.1;
            app.Q125.ValueChangingFcn = createCallbackFcn(app, @Q125ValueChanging, true);
            app.Q125.Limits = [0.1 50];
            app.Q125.ValueDisplayFormat = '%.2f';
            app.Q125.Editable = 'off';
            app.Q125.Enable = 'off';
            app.Q125.Value = 1;
            app.Freq62text = uilabel(app.Tab);
            app.Freq62text.Text = '62Hz';
            app.Freq62 = uislider(app.Tab);
            app.Freq62.Limits = [-12 12];
            app.Freq62.MajorTicks = [];
            app.Freq62.ValueChangedFcn = createCallbackFcn(app, @Freq62ValueChanged, true);
            app.Freq62.MinorTicks = [];
            app.Freq62.Enable = 'off';
            app.dbLabel62 = uilabel(app.Tab);
            app.dbLabel62.Text = '0db';
            app.Spinner_5Label_5 = uilabel(app.Tab);
            app.Spinner_5Label_5.Text = '品质因数';
            app.Q62 = uispinner(app.Tab);
            app.Q62.Step = 0.1;
            app.Q62.ValueChangingFcn = createCallbackFcn(app, @Q62ValueChanging, true);
            app.Q62.Limits = [0.1 50];
            app.Q62.ValueDisplayFormat = '%.2f';
            app.Q62.Editable = 'off';
            app.Q62.Enable = 'off';
            app.Q62.Value = 1;
            app.Freq31text = uilabel(app.Tab);
            app.Freq31text.Text = '31Hz';
            app.Freq31 = uislider(app.Tab);
            app.Freq31.Limits = [-12 12];
            app.Freq31.MajorTicks = [];
            app.Freq31.ValueChangedFcn = createCallbackFcn(app, @Freq31ValueChanged, true);
            app.Freq31.MinorTicks = [];
            app.Freq31.Enable = 'off';
            app.dbLabel31 = uilabel(app.Tab);
            app.dbLabel31.Text = '0db';
            app.Spinner_5Label_6 = uilabel(app.Tab);
            app.Spinner_5Label_6.Text = '品质因数';
            app.Q31 = uispinner(app.Tab);
            app.Q31.Step = 0.1;
            app.Q31.ValueChangingFcn = createCallbackFcn(app, @Q31ValueChanging, true);
            app.Q31.Limits = [0.1 50];
            app.Q31.ValueDisplayFormat = '%.2f';
            app.Q31.Editable = 'off';
            app.Q31.Enable = 'off';
            app.Q31.Value = 1;
            app.Tab_2 = uitab(app.TabGroup2);
            app.Tab_2.Title = '通道2';
            app.synchronousButton_2 = uibutton(app.Tab_2, 'push');
            app.synchronousButton_2.ButtonPushedFcn = createCallbackFcn(app, @synchronousButton_2Pushed, true);
            app.synchronousButton_2.Enable = 'off';
            app.synchronousButton_2.Text = '同步通道1';
            app.Freq31text_2 = uilabel(app.Tab_2);
            app.Freq31text_2.Text = '31Hz';
            app.Freq31_2 = uislider(app.Tab_2);
            app.Freq31_2.Limits = [-12 12];
            app.Freq31_2.MajorTicks = [];
            app.Freq31_2.ValueChangedFcn = createCallbackFcn(app, @Freq31_2ValueChanged, true);
            app.Freq31_2.MinorTicks = [];
            app.Freq31_2.Enable = 'off';
            app.dbLabel31_2 = uilabel(app.Tab_2);
            app.dbLabel31_2.Text = '0db';
            app.Spinner_5Label_7 = uilabel(app.Tab_2);
            app.Spinner_5Label_7.Text = '品质因数';
            app.Q31_2 = uispinner(app.Tab_2);
            app.Q31_2.Step = 0.1;
            app.Q31_2.ValueChangingFcn = createCallbackFcn(app, @Q31_2ValueChanging, true);
            app.Q31_2.Limits = [0.1 50];
            app.Q31_2.ValueDisplayFormat = '%.2f';
            app.Q31_2.Editable = 'off';
            app.Q31_2.Enable = 'off';
            app.Q31_2.Value = 1;
            app.Freq62text_2 = uilabel(app.Tab_2);
            app.Freq62text_2.Text = '61Hz';
            app.Freq62_2 = uislider(app.Tab_2);
            app.Freq62_2.Limits = [-12 12];
            app.Freq62_2.MajorTicks = [];
            app.Freq62_2.ValueChangedFcn = createCallbackFcn(app, @Freq62_2ValueChanged, true);
            app.Freq62_2.MinorTicks = [];
            app.Freq62_2.Enable = 'off';
            app.dbLabel62_2 = uilabel(app.Tab_2);
            app.dbLabel62_2.Text = '0db';
            app.Spinner_5Label_8 = uilabel(app.Tab_2);
            app.Spinner_5Label_8.Text = '品质因数';
            app.Q62_2 = uispinner(app.Tab_2);
            app.Q62_2.Step = 0.1;
            app.Q62_2.ValueChangingFcn = createCallbackFcn(app, @Q62_2ValueChanging, true);
            app.Q62_2.Limits = [0.1 50];
            app.Q62_2.ValueDisplayFormat = '%.2f';
            app.Q62_2.Editable = 'off';
            app.Q62_2.Enable = 'off';
            app.Q62_2.Value = 1;
            app.Freq125text_2 = uilabel(app.Tab_2);
            app.Freq125text_2.Text = '125Hz';
            app.Freq125_2 = uislider(app.Tab_2);
            app.Freq125_2.Limits = [-12 12];
            app.Freq125_2.MajorTicks = [];
            app.Freq125_2.ValueChangedFcn = createCallbackFcn(app, @Freq125_2ValueChanged, true);
            app.Freq125_2.MinorTicks = [];
            app.Freq125_2.Enable = 'off';
            app.dbLabel125_2 = uilabel(app.Tab_2);
            app.dbLabel125_2.Text = '0db';
            app.Spinner_5Label_9 = uilabel(app.Tab_2);
            app.Spinner_5Label_9.Text = '品质因数';
            app.Q125_2 = uispinner(app.Tab_2);
            app.Q125_2.Step = 0.1;
            app.Q125_2.ValueChangingFcn = createCallbackFcn(app, @Q125_2ValueChanging, true);
            app.Q125_2.Limits = [0.1 50];
            app.Q125_2.ValueDisplayFormat = '%.2f';
            app.Q125_2.Editable = 'off';
            app.Q125_2.Enable = 'off';
            app.Q125_2.Value = 1;
            app.Freq250text_2 = uilabel(app.Tab_2);
            app.Freq250text_2.Text = '250Hz';
            app.Freq250_2 = uislider(app.Tab_2);
            app.Freq250_2.Limits = [-12 12];
            app.Freq250_2.MajorTicks = [];
            app.Freq250_2.ValueChangedFcn = createCallbackFcn(app, @Freq250_2ValueChanged, true);
            app.Freq250_2.MinorTicks = [];
            app.Freq250_2.Enable = 'off';
            app.dbLabel250_2 = uilabel(app.Tab_2);
            app.dbLabel250_2.Text = '0db';
            app.Spinner_5Label_10 = uilabel(app.Tab_2);
            app.Spinner_5Label_10.Text = '品质因数';
            app.Q250_2 = uispinner(app.Tab_2);
            app.Q250_2.Step = 0.1;
            app.Q250_2.ValueChangingFcn = createCallbackFcn(app, @Q250_2ValueChanging, true);
            app.Q250_2.Limits = [0.1 50];
            app.Q250_2.ValueDisplayFormat = '%.2f';
            app.Q250_2.Editable = 'off';
            app.Q250_2.Enable = 'off';
            app.Q250_2.Value = 1;
            app.Freq500text_2 = uilabel(app.Tab_2);
            app.Freq500text_2.Text = '500Hz';
            app.Freq500_2 = uislider(app.Tab_2);
            app.Freq500_2.Limits = [-12 12];
            app.Freq500_2.MajorTicks = [];
            app.Freq500_2.ValueChangedFcn = createCallbackFcn(app, @Freq500_2ValueChanged, true);
            app.Freq500_2.MinorTicks = [];
            app.Freq500_2.Enable = 'off';
            app.dbLabel500_2 = uilabel(app.Tab_2);
            app.dbLabel500_2.Text = '0db';
            app.Spinner_5Label_11 = uilabel(app.Tab_2);
            app.Spinner_5Label_11.Text = '品质因数';
            app.Q500_2 = uispinner(app.Tab_2);
            app.Q500_2.Step = 0.1;
            app.Q500_2.ValueChangingFcn = createCallbackFcn(app, @Q500_2ValueChanging, true);
            app.Q500_2.Limits = [0.1 50];
            app.Q500_2.ValueDisplayFormat = '%.2f';
            app.Q500_2.Editable = 'off';
            app.Q500_2.Enable = 'off';
            app.Q500_2.Value = 1;
            app.Freq1ktext_2 = uilabel(app.Tab_2);
            app.Freq1ktext_2.Text = '1kHz';
            app.Freq1k_2 = uislider(app.Tab_2);
            app.Freq1k_2.Limits = [-12 12];
            app.Freq1k_2.MajorTicks = [];
            app.Freq1k_2.ValueChangedFcn = createCallbackFcn(app, @Freq1k_2ValueChanged, true);
            app.Freq1k_2.MinorTicks = [];
            app.Freq1k_2.Enable = 'off';
            app.dbLabel1k_2 = uilabel(app.Tab_2);
            app.dbLabel1k_2.Text = '0db';
            app.Spinner_6Label_2 = uilabel(app.Tab_2);
            app.Spinner_6Label_2.Text = '品质因数';
            app.Q1k_2 = uispinner(app.Tab_2);
            app.Q1k_2.Step = 0.1;
            app.Q1k_2.ValueChangingFcn = createCallbackFcn(app, @Q1k_2ValueChanging, true);
            app.Q1k_2.Limits = [0.1 50];
            app.Q1k_2.ValueDisplayFormat = '%.2f';
            app.Q1k_2.Editable = 'off';
            app.Q1k_2.Enable = 'off';
            app.Q1k_2.Value = 1;
            app.Freq2ktext_2 = uilabel(app.Tab_2);
            app.Freq2ktext_2.Text = '2kHz';
            app.Freq2k_2 = uislider(app.Tab_2);
            app.Freq2k_2.Limits = [-12 12];
            app.Freq2k_2.MajorTicks = [];
            app.Freq2k_2.ValueChangedFcn = createCallbackFcn(app, @Freq2k_2ValueChanged, true);
            app.Freq2k_2.MinorTicks = [];
            app.Freq2k_2.Enable = 'off';
            app.dbLabel2k_2 = uilabel(app.Tab_2);
            app.dbLabel2k_2.Text = '0db';
            app.Spinner_7Label_2 = uilabel(app.Tab_2);
            app.Spinner_7Label_2.Text = '品质因数';
            app.Q2k_2 = uispinner(app.Tab_2);
            app.Q2k_2.Step = 0.1;
            app.Q2k_2.ValueChangingFcn = createCallbackFcn(app, @Q2k_2ValueChanging, true);
            app.Q2k_2.Limits = [0.1 50];
            app.Q2k_2.ValueDisplayFormat = '%.2f';
            app.Q2k_2.Editable = 'off';
            app.Q2k_2.Enable = 'off';
            app.Q2k_2.Value = 1;
            app.Freq4ktext_2 = uilabel(app.Tab_2);
            app.Freq4ktext_2.Text = '4kHz';
            app.Freq4k_2 = uislider(app.Tab_2);
            app.Freq4k_2.Limits = [-12 12];
            app.Freq4k_2.MajorTicks = [];
            app.Freq4k_2.ValueChangedFcn = createCallbackFcn(app, @Freq4k_2ValueChanged, true);
            app.Freq4k_2.MinorTicks = [];
            app.Freq4k_2.Enable = 'off';
            app.dbLabel4k_2 = uilabel(app.Tab_2);
            app.dbLabel4k_2.Text = '0db';
            app.Spinner_8Label_2 = uilabel(app.Tab_2);
            app.Spinner_8Label_2.Text = '品质因数';
            app.Q4k_2 = uispinner(app.Tab_2);
            app.Q4k_2.Step = 0.1;
            app.Q4k_2.ValueChangingFcn = createCallbackFcn(app, @Q4k_2ValueChanging, true);
            app.Q4k_2.Limits = [0.1 50];
            app.Q4k_2.ValueDisplayFormat = '%.2f';
            app.Q4k_2.Editable = 'off';
            app.Q4k_2.Enable = 'off';
            app.Q4k_2.Value = 1;
            app.Freq8ktext_2 = uilabel(app.Tab_2);
            app.Freq8ktext_2.Text = '8kHz';
            app.Freq8k_2 = uislider(app.Tab_2);
            app.Freq8k_2.Limits = [-12 12];
            app.Freq8k_2.MajorTicks = [];
            app.Freq8k_2.ValueChangedFcn = createCallbackFcn(app, @Freq8k_2ValueChanged, true);
            app.Freq8k_2.MinorTicks = [];
            app.Freq8k_2.Enable = 'off';
            app.dbLabel8k_2 = uilabel(app.Tab_2);
            app.dbLabel8k_2.Text = '0db';
            app.Spinner_9Label_2 = uilabel(app.Tab_2);
            app.Spinner_9Label_2.Text = '品质因数';
            app.Q8k_2 = uispinner(app.Tab_2);
            app.Q8k_2.Step = 0.1;
            app.Q8k_2.ValueChangingFcn = createCallbackFcn(app, @Q8k_2ValueChanging, true);
            app.Q8k_2.Limits = [0.1 50];
            app.Q8k_2.ValueDisplayFormat = '%.2f';
            app.Q8k_2.Editable = 'off';
            app.Q8k_2.Enable = 'off';
            app.Q8k_2.Value = 1;
            app.Freq16ktext_2 = uilabel(app.Tab_2);
            app.Freq16ktext_2.Text = '16kHz';
            app.Freq16k_2 = uislider(app.Tab_2);
            app.Freq16k_2.Limits = [-12 12];
            app.Freq16k_2.MajorTicks = [];
            app.Freq16k_2.ValueChangedFcn = createCallbackFcn(app, @Freq16k_2ValueChanged, true);
            app.Freq16k_2.MinorTicks = [];
            app.Freq16k_2.Enable = 'off';
            app.dbLabel16k_2 = uilabel(app.Tab_2);
            app.dbLabel16k_2.Text = '0db';
            app.Spinner_10Label_2 = uilabel(app.Tab_2);
            app.Spinner_10Label_2.Text = '品质因数';
            app.Q16k_2 = uispinner(app.Tab_2);
            app.Q16k_2.Step = 0.1;
            app.Q16k_2.ValueChangingFcn = createCallbackFcn(app, @Q16k_2ValueChanging, true);
            app.Q16k_2.Limits = [0.1 50];
            app.Q16k_2.ValueDisplayFormat = '%.2f';
            app.Q16k_2.Editable = 'off';
            app.Q16k_2.Enable = 'off';
            app.Q16k_2.Value = 1;
        end
    end

    methods (Access = public)

        % Construct app
        function app = audioequalizer

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end